classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        InitiObjectsButton         matlab.ui.control.Button
        SamplesSearchButton        matlab.ui.control.Button
        EndAllSerialButton         matlab.ui.control.Button
        CalibrateButton            matlab.ui.control.Button
        ReturnHomeButton           matlab.ui.control.Button
        StopSearchButton           matlab.ui.control.StateButton
        dataTable                  matlab.ui.control.Table
        PlantImagingPlatformLabel  matlab.ui.control.Label
        ShowCurrentImageButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        Objects % Description
        
        imgTable = cell2table(cell(0,4),...
                'VariableNames', {'SampleNum', 'FileName', 'XLocation', 'YLocation'}); % Description
        StopValue % Description
    end
    
    methods (Access = private)
        
        function [] = SearchSystem(app, varargin )
            %UNTITLED Summary of this function goes here
            %   Detailed explanation goes here
            p = inputParser;
            validPlatformObject = @(x) isa(x, 'PlatformObjects');
            defaultDirection = 1;
            validDirection = [1,-1];
            checkDirection = @(x) any(ismember(x,validDirection));
            defaultTable = cell2table(cell(0,4),...
                'VariableNames', {'SampleNum', 'FileName', 'XLocation', 'YLocation'});
            defaultOutput = fullfile('output',datestr(now,'yyyy_mm_dd-HH_MM_SS'));
            addRequired(p,'objects',validPlatformObject);
            addOptional(p,'imgTable',defaultTable,@istable);
            addParameter(p,'direction',defaultDirection,checkDirection);
            addParameter(p,'output',defaultOutput,@isdir);
            parse(p,app.Objects,varargin{:});
            app.imgTable= p.Results.imgTable;
            direction = p.Results.direction;
            output = p.Results.output;
            if ~(7 == exist(output,'dir'))
                mkdir(output)
            end
            
            preview(p.Results.objects.VidObj);
            if p.Results.direction == 1
                app.Objects.CurX=(p.Results.objects.Cal.LRextents(2)+50); % move all the way to te left
                moveY=(p.Results.objects.Cal.TBextents(2)+50); % move all the way to the Top
                app.Objects.CurY = moveY;
            elseif p.Results.direction == -1
                app.Objects.CurX=(p.Results.objects.Cal.LRextents(1)-50); % move all the way to the right
                moveY=(p.Results.objects.Cal.TBextents(1)-50); % move all the way to the Top
                app.Objects.CurY = moveY;
            end
            outputCom = serialCom.writeToSerial(p.Results.objects.Ymotor,'$');
            statusInt = str2num(outputCom(5:end)); %#ok<ST2NM>
            statusBin = dec2binvec(statusInt,8);
            while (~statusBin(3))
                try
                
                app.Objects.CurY = moveY;
                disp(app.Objects.CurY)
                [app.Objects,app.imgTable] = searchRow(p.Results.objects,'step',floor(p.Results.objects.Xstep*1.2),'output',output,'direction',direction,'imgTable',app.imgTable);
                app.dataTable.Data = table2cell(app.imgTable);
                direction = direction*-1;
                moveY = double(app.Objects.CurY + floor(app.Objects.Ystep*1.2));
                outputCom = serialCom.writeToSerial(p.Results.objects.Ymotor,'$');
                statusInt = str2num(outputCom(5:end)); %#ok<ST2NM>
                statusBin = dec2binvec(statusInt,8);
                if app.StopValue == 1
                    break
                end
                catch Err
                    disp(Err);
                end
            end
            serialCom.writeToSerial(p.Results.objects.Ymotor,'D');
            app.Objects.CurY = 0;
            app.Objects.CurX = 0;
        end
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
        end

        % Button pushed function: InitiObjectsButton
        function InitiObjectsButtonPushed(app, event)
            app.Objects = PlatformObjects;
            app.InitiObjectsButton.Enable = 'off';
        end

        % Button pushed function: SamplesSearchButton
        function SamplesSearchButtonPushed(app, event)
            SearchSystem(app,'imgTable',app.imgTable);
            app.StopSearchButton.Value = 0;
        end

        % Button pushed function: EndAllSerialButton
        function EndAllSerialButtonPushed(app, event)
            serialCom.endAllSerial;
            app.InitiObjectsButton.Enable = 'on';
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
              app.Objects.Cal = app.Objects.calibration;
        end

        % Button pushed function: ReturnHomeButton
        function ReturnHomeButtonPushed(app, event)
            serialCom.returnHome(app.Objects.Xmotor);
            serialCom.returnHome(app.Objects.Ymotor);
        end

        % Value changed function: StopSearchButton
        function StopSearchButtonValueChanged(app, event)
            value = app.StopSearchButton.Value;
            if value == 1 
                app.StopValue = value;
            end
        end

        % Cell selection callback: dataTable
        function dataTableCellSelection(app, event)
            
        end

        % Button pushed function: ShowCurrentImageButton
        function ShowCurrentImageButtonPushed(app, event)
            img = app.Objects.CurImg;
            imshow(img);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.InitiObjectsButton = uibutton(app.UIFigure, 'push');
            app.InitiObjectsButton.ButtonPushedFcn = createCallbackFcn(app, @InitiObjectsButtonPushed, true);
            app.InitiObjectsButton.Text = 'Initialize All Objects';
            app.SamplesSearchButton = uibutton(app.UIFigure, 'push');
            app.SamplesSearchButton.ButtonPushedFcn = createCallbackFcn(app, @SamplesSearchButtonPushed, true);
            app.SamplesSearchButton.Text = 'Search For Samples';
            app.EndAllSerialButton = uibutton(app.UIFigure, 'push');
            app.EndAllSerialButton.ButtonPushedFcn = createCallbackFcn(app, @EndAllSerialButtonPushed, true);
            app.EndAllSerialButton.Text = 'End All Serial';
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.ReturnHomeButton = uibutton(app.UIFigure, 'push');
            app.ReturnHomeButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnHomeButtonPushed, true);
            app.ReturnHomeButton.Text = 'Return Home';
            app.StopSearchButton = uibutton(app.UIFigure, 'state');
            app.StopSearchButton.ValueChangedFcn = createCallbackFcn(app, @StopSearchButtonValueChanged, true);
            app.StopSearchButton.Text = 'Stop Search';
            app.dataTable = uitable(app.UIFigure);
            app.dataTable.ColumnName = {'SampleNum'; 'FileName'; 'XLoc'; 'YLoc'};
            app.dataTable.RowName = {};
            app.dataTable.CellSelectionCallback = createCallbackFcn(app, @dataTableCellSelection, true);
            app.PlantImagingPlatformLabel = uilabel(app.UIFigure);
            app.PlantImagingPlatformLabel.Text = 'Plant Imaging Platform';
            app.ShowCurrentImageButton = uibutton(app.UIFigure, 'push');
            app.ShowCurrentImageButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCurrentImageButtonPushed, true);
            app.ShowCurrentImageButton.Text = 'Show Current Image';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end