classdef App2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        InitiObjectsButton         matlab.ui.control.Button
        SamplesSearchButton        matlab.ui.control.Button
        EndAllSerialButton         matlab.ui.control.Button
        CalibrateButton            matlab.ui.control.Button
        ReturnHomeButton           matlab.ui.control.Button
        StopSearchButton           matlab.ui.control.StateButton
        dataTable                  matlab.ui.control.Table
        PlantImagingPlatformLabel  matlab.ui.control.Label
        ShowCurrentImageButton     matlab.ui.control.Button
        XMoveEditFieldLabel        matlab.ui.control.Label
        XMoveField                 matlab.ui.control.NumericEditField
        YMoveEditFieldLabel        matlab.ui.control.Label
        YMoveField                 matlab.ui.control.NumericEditField
        GoButton                   matlab.ui.control.Button
        SaveCurrentImageButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        Objects % Description
        
        imgTable = cell2table(cell(0,4),...
                'VariableNames', {'SampleNum', 'FileName', 'XLocation', 'YLocation'}); % Description
        StopValue % Description
    end
    
    methods (Access = private)
        
        function SearchSystem(app)
            %UNTITLED Summary of this function goes here
            %   Detailed explanation goes here
            defaultDirection = 1;
            defaultOutput    = fullfile('output',datestr(now,'yyyy_mm_dd-HH_MM_SS'));
            
            direction = defaultDirection;
            output    = defaultOutput;
            
            if ~(7 == exist(output,'dir'))
                mkdir(output)
            end
            
            preview(app.Objects.VidObj);
            
            app.Objects.CurX=(app.Objects.Cal.LRextents(2)+50); % move all the way to te left
            moveY=(app.Objects.Cal.TBextents(2)+50); % move all the way to the Top
            app.Objects.CurY = moveY;
            
            outputCom = serialCom.writeToSerial(app.Objects.Ymotor,'$');
            statusInt = str2num(outputCom(5:end)); %#ok<ST2NM>
            statusBin = dec2binvec(statusInt,8);
            while (~statusBin(3))
                try
                
                app.Objects.CurY = moveY;
                disp(app.Objects.CurY)
                [app.Objects,app.imgTable] = searchRow(app.Objects,'step',floor(app.Objects.Xstep*1.2),'output',output,'direction',direction,'imgTable',app.imgTable);
                app.dataTable.Data = table2cell(app.imgTable);
                direction = direction*-1;
                moveY = double(app.Objects.CurY + floor(app.Objects.Ystep*1.2));
                outputCom = serialCom.writeToSerial(app.Objects.Ymotor,'$');
                statusInt = str2num(outputCom(5:end)); %#ok<ST2NM>
                statusBin = dec2binvec(statusInt,8);
                if app.StopValue == 1
                    break
                end
                catch Err
                    error(Err.message);
                end
            end
            serialCom.writeToSerial(app.Objects.Ymotor,'D');
            app.Objects.CurY = 0;
            app.Objects.CurX = 0;
        end
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.CalibrateButton.Enable = 'off';
           app.ReturnHomeButton.Enable = 'off';
           app.StopSearchButton.Enable = 'off';
           app.ShowCurrentImageButton.Enable = 'off';
           app.GoButton.Enable = 'off';
           app.SamplesSearchButton.Enable = 'off';
        end

        % Button pushed function: InitiObjectsButton
        function InitiObjectsButtonPushed(app, event)
            app.Objects = PlatformObjects;
            app.InitiObjectsButton.Enable = 'off';
            app.CalibrateButton.Enable = 'on';
           app.ReturnHomeButton.Enable = 'on';
           app.StopSearchButton.Enable = 'on';
           app.ShowCurrentImageButton.Enable = 'on';
           app.GoButton.Enable = 'on';
           app.SamplesSearchButton.Enable = 'on';
        end

        % Button pushed function: SamplesSearchButton
        function SamplesSearchButtonPushed(app, event)
            SearchSystem(app);
            app.StopSearchButton.Value = 0;
        end

        % Button pushed function: EndAllSerialButton
        function EndAllSerialButtonPushed(app, event)
            serialCom.endAllSerial;
            app.InitiObjectsButton.Enable = 'on';
            app.CalibrateButton.Enable = 'off';
           app.ReturnHomeButton.Enable = 'off';
           app.StopSearchButton.Enable = 'off';
           app.ShowCurrentImageButton.Enable = 'off';
           app.GoButton.Enable = 'off';
           app.SamplesSearchButton.Enable = 'off';
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
              app.Objects.Cal = app.Objects.calibration;
        end

        % Button pushed function: ReturnHomeButton
        function ReturnHomeButtonPushed(app, event)
            serialCom.returnHome(app.Objects.Xmotor);
            serialCom.returnHome(app.Objects.Ymotor);
        end

        % Value changed function: StopSearchButton
        function StopSearchButtonValueChanged(app, event)
            value = app.StopSearchButton.Value;
            if value == 1 
                app.StopValue = value;
            end
        end

        % Cell selection callback: dataTable
        function dataTableCellSelection(app, event)
            
        end

        % Button pushed function: ShowCurrentImageButton
        function ShowCurrentImageButtonPushed(app, event)
            preview(app.Objects.VidObj);
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            xmove = app.XMoveField.Value;
            ymove = app.YMoveField.Value;
            app.Objects.CurX = app.Objects.CurX + xmove;
            app.Objects.CurY = app.Objects.CurY + ymove;
        end

        % Button pushed function: SaveCurrentImageButton
        function SaveCurrentImageButtonPushed(app, event)
          [FileName,PathName] = uiputfile({'*.jpg;*.tif;*.png;*.gif','All Image Files';...
          '*.*','All Files' },'Save Image',...
          'C:\Work\newfile.jpg')
      
          
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.InitiObjectsButton = uibutton(app.UIFigure, 'push');
            app.InitiObjectsButton.ButtonPushedFcn = createCallbackFcn(app, @InitiObjectsButtonPushed, true);
            app.InitiObjectsButton.Text = 'Initialize All Objects';
            app.SamplesSearchButton = uibutton(app.UIFigure, 'push');
            app.SamplesSearchButton.ButtonPushedFcn = createCallbackFcn(app, @SamplesSearchButtonPushed, true);
            app.SamplesSearchButton.Text = 'Search For Samples';
            app.EndAllSerialButton = uibutton(app.UIFigure, 'push');
            app.EndAllSerialButton.ButtonPushedFcn = createCallbackFcn(app, @EndAllSerialButtonPushed, true);
            app.EndAllSerialButton.Text = 'End All Serial';
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.ReturnHomeButton = uibutton(app.UIFigure, 'push');
            app.ReturnHomeButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnHomeButtonPushed, true);
            app.ReturnHomeButton.Text = 'Return Home';
            app.StopSearchButton = uibutton(app.UIFigure, 'state');
            app.StopSearchButton.ValueChangedFcn = createCallbackFcn(app, @StopSearchButtonValueChanged, true);
            app.StopSearchButton.Text = 'Stop Search';
            app.dataTable = uitable(app.UIFigure);
            app.dataTable.ColumnName = {'SampleNum'; 'FileName'; 'XLoc'; 'YLoc'};
            app.dataTable.RowName = {};
            app.dataTable.CellSelectionCallback = createCallbackFcn(app, @dataTableCellSelection, true);
            app.PlantImagingPlatformLabel = uilabel(app.UIFigure);
            app.PlantImagingPlatformLabel.Text = 'Plant Imaging Platform';
            app.ShowCurrentImageButton = uibutton(app.UIFigure, 'push');
            app.ShowCurrentImageButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCurrentImageButtonPushed, true);
            app.ShowCurrentImageButton.Text = 'Show Current Image';
            app.XMoveEditFieldLabel = uilabel(app.UIFigure);
            app.XMoveEditFieldLabel.Text = 'X Move';
            app.XMoveField = uieditfield(app.UIFigure, 'numeric');
            app.YMoveEditFieldLabel = uilabel(app.UIFigure);
            app.YMoveEditFieldLabel.Text = 'Y Move';
            app.YMoveField = uieditfield(app.UIFigure, 'numeric');
            app.YMoveField.ValueDisplayFormat = '%.0f';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.SaveCurrentImageButton = uibutton(app.UIFigure, 'push');
            app.SaveCurrentImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentImageButtonPushed, true);
            app.SaveCurrentImageButton.Text = 'Save Current Image';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App2()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end