classdef HyphaeSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        StartingImgUIAxes     matlab.ui.control.UIAxes
        HyphaeButton          matlab.ui.control.Button
        NotHyphaeButton       matlab.ui.control.Button
        FolderEditFieldLabel  matlab.ui.control.Label
        FolderEditField       matlab.ui.control.EditField
        BrowseButton          matlab.ui.control.Button
        GoBack1Button         matlab.ui.control.Button
        DropDown              matlab.ui.control.DropDown
        fibrousStruct         matlab.ui.control.UIAxes
        HoughTransform        matlab.ui.control.UIAxes
        CandidatePlot         matlab.ui.control.UIAxes
        fig3                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fileList % Description
        folder % Description
        fileNum % Description
        lineNum
        hyphaeCnt
        featureMatrix
        targetMatrix
        
        %preDefined
        thickness = [7]; % Fiber thickness in pixels
        rhoRes = 3; % rho resolution for Hough transform, [pixels]
        thetaRes = 1; % angle resolution for Hough transform, [degrees]
        numPeaksLimit = 30; % The maximum number of peaks returned by houghpeaks()
        peakThreshold = 100; %100; % Threshold of Hough transform peak value
        fillGapLen = 20; % Maximum gap size defining line segments, [pixels]
        minLineLen = 200; %Minimum line segment length returned by houghlines(), [pixels]
        peakOverAvgThres = 3;
        adjacentLineRatioThres = 0.15;
        lineLengthThres = 200; % [pixels]
        d = [-8,-4,4,8]; %8; % Adjacent line disance away from line, [pixel length]
        
        
        B % Description
        BW % Description
        H % Description
        T % Description
        R % Description
        x % Description
        y % Description
        xyForPlot % Description
        numLines % Description
        isHyphae % Description
        hp % Description
    end
    
    methods (Access = private)
        
        function processImage(app)
            leafImage = imread(fullfile(app.folder,app.fileList(app.fileNum).name));
            leafImage = leafImage(:,:,3); % Blue channel
            findHyphaeApp(app,leafImage);
        end
        
        function findHyphaeApp(app,leafImage)
            theta = -90:app.thetaRes:90-app.thetaRes; % Degrees
            app.B = fibermetric(leafImage, app.thickness, 'ObjectPolarity','bright'); %, 'StructureSensitivity', 3);
            % Length of Hough transform lines
            I = ones(size(app.B));
            [Hlen,~,~] = hough(I,'RhoResolution',app.rhoRes,'Theta',theta);
            % Image binarization
            app.T = adaptthresh(app.B, 0.2,'Statistic','mean'); % 0.2
            app.BW = imbinarize(app.B,app.T); %'adaptive');%,0.50);
            % Hough transform
            [app.H,app.T,app.R] = hough(app.BW,'RhoResolution',app.rhoRes,'Theta',theta);
            H2 = app.H./Hlen;
            H2(isnan(H2)) = 1;
            % Find peaks that are high in both transforms
            app.H = round(app.H.*H2);
            P = houghpeaks(app.H,app.numPeaksLimit,'Threshold',app.peakThreshold);
            % Find line segments of possible hyphae
            lines = houghlines(app.BW,app.T,app.R,P,'FillGap',app.fillGapLen,'MinLength',app.minLineLen);
            app.numLines = length(lines);
            peakRowColumn(1,:) = P(1,:);
            Pindex = 1;
            for loop = 2:app.numLines
                if (lines(loop).rho~=lines(loop-1).rho && lines(loop).theta~=lines(loop-1).theta)
                    Pindex = Pindex + 1;
                end
                peakRowColumn(loop,:) = P(Pindex,:);
            end
            
            % Compute feature criteria
            % Feature: Hough transform peak over mean
            peakOverAvg = zeros(app.numLines,1);
            H1 = app.H(:);
            meanH = mean(H1(H1~=0)); % Mean of non-zero array elements
            for loop = 1:app.numLines
                peakOverAvg(loop) = app.H(peakRowColumn(loop,1),peakRowColumn(loop,2))/meanH;
            end
            
            % Feature: Adjacent line segment intensity ratio
            % **** Modify to include mulitple adjacent lines different distances away *****
            adjacentLineRatio = zeros(app.numLines,1);
            app.xyForPlot = zeros(2,2,app.numLines);
            xyAdjForPlot = zeros(2,2,app.numLines,length(app.d));
            for loop = 1:app.numLines
                xy = [lines(loop).point1;lines(loop).point2];
                lineProfile = improfile(app.BW,xy(:,1), xy(:,2));
                lineDensity = mean(lineProfile);
                % adjacent parallel line
                for loopd = 1:length(app.d)
                    if lines(loop).theta<0
                        adjPoint1 = [lines(loop).point1(1)-app.d(loopd)*cos(lines(loop).theta*pi/180),lines(loop).point1(2)-app.d(loopd)*sin(lines(loop).theta*pi/180)]; % [x,y]
                        adjPoint2 = [lines(loop).point2(1)-app.d(loopd)*cos(lines(loop).theta*pi/180),lines(loop).point2(2)-app.d(loopd)*sin(lines(loop).theta*pi/180)]; % [x,y]
                    else
                        adjPoint1 = [lines(loop).point1(1)+app.d(loopd)*cos(lines(loop).theta*pi/180),lines(loop).point1(2)+app.d(loopd)*sin(lines(loop).theta*pi/180)]; % [x,y]
                        adjPoint2 = [lines(loop).point2(1)+app.d(loopd)*cos(lines(loop).theta*pi/180),lines(loop).point2(2)+app.d(loopd)*sin(lines(loop).theta*pi/180)]; % [x,y]
                    end
                    xyAdj = [adjPoint1;adjPoint2];
                    
                    app.xyForPlot(:,:,loop) = xy;
                    xyAdjForPlot(:,:,loop,loopd) = xyAdj;
                    
                    lineAdjProfile = improfile(app.BW,xyAdj(:,1), xyAdj(:,2));
                    lineAdjProfile(isnan(lineAdjProfile)) = []; % Remove NaN points caused by line extennding beyond image boundaries
                    if isempty(lineAdjProfile)
                        adjacentLineRatio(loop,loopd) = 1;
                    else
                        lineDensityAdj = mean(lineAdjProfile);
                        adjacentLineRatio(loop,loopd) = lineDensityAdj/lineDensity;
                        if isnan(adjacentLineRatio(loop,loopd))
                            disp('NaN')
                        end
                    end
                end
            end
            
            % Feature: Line segment density, density standard deviation,
            lineMean = zeros(1,app.numLines);
            lineVar= zeros(1,app.numLines);
            lineMoment3= zeros(1,app.numLines);
            lineMoment4= zeros(1,app.numLines);
            for loop = 1:app.numLines
                xy = [lines(loop).point1;lines(loop).point2];
                lineProfile = improfile(leafImage,xy(:,1), xy(:,2));
                lineMean(loop) = mean(lineProfile)/mean2(leafImage);
                profileStd = std(lineProfile,1); % sample std, not population estimate
                lineVar(loop) = moment(lineProfile,2)/mean(lineProfile)^2;
                lineMoment3(loop) = moment(lineProfile,3)/profileStd^3;
                lineMoment4(loop) = moment(lineProfile,4)/profileStd^4;
            end
            
            % Features: Line segment length, theta and rho
            lineLength = zeros(app.numLines,1);
            lineTheta = zeros(app.numLines,1);
            lineRho = zeros(app.numLines,1);
            for loop = 1:app.numLines
                lineLength(loop) = 0.001*(norm(lines(loop).point1 - lines(loop).point2)); % Divide by 1000 to normlize values
                lineTheta(loop) = lines(loop).theta;
                lineRho(loop) = lines(loop).rho;
            end
            
            % Apply feature criteria to find lines that are hyphae
            app.isHyphae = false(app.numLines,1);
            for loop = 1:app.numLines
                app.isHyphae(loop) = peakOverAvg(loop) > app.peakOverAvgThres &&...
                    adjacentLineRatio(loop,1) < app.adjacentLineRatioThres &&...
                    lineLength(loop) > 0.001*app.lineLengthThres;
            end
            app.hyphaeCnt = nnz(app.isHyphae);
            
            imshow(leafImage,'InitialMagnification','fit','Parent',app.StartingImgUIAxes);
            title(app.StartingImgUIAxes,'Blue Channel Activation');
            axis(app.StartingImgUIAxes,'off');
            imshow(app.B*3,'InitialMagnification','fit','Parent',app.fibrousStruct); % x3
            title(app.StartingImgUIAxes,'Fibermetrics Results');
            axis(app.fibrousStruct,'off');
            imshow(app.BW,'InitialMagnification','fit','Parent',app.fig3)
            title(app.fig3,'Figure 3');
            axis(app.fig3,'off');
            imshow(app.H,[],'XData',app.T,'YData',app.R,'InitialMagnification','fit','Parent',app.HoughTransform);
            axis(app.HoughTransform,'square');
            xlabel(app.HoughTransform,'\theta');
            ylabel(app.HoughTransform,'\rho');
            title(app.HoughTransform,['Hough Transform, H (H^2/lenght)',', max(H) = ',num2str(max(app.H(:)),'%d')]);
            app.x = app.T(peakRowColumn(:,2));
            app.y = app.R(peakRowColumn(:,1));
            hold(app.HoughTransform,'on');
            plot(app.HoughTransform,app.x,app.y,'o','color','red');
            hold(app.HoughTransform,'off');
            imshow(app.BW,'InitialMagnification','fit','Parent',app.CandidatePlot);
            title(app.fig3,'Hyphae Candidate');
            axis(app.CandidatePlot,'off');
            hold(app.CandidatePlot,'on');
            app.lineNum = 1;
            hpAdj = plot(app.CandidatePlot,xyAdjForPlot(:,1,1,app.lineNum), xyAdjForPlot(:,2,1,app.lineNum),'LineWidth',1,'Color','red');
           
            hold(app.CandidatePlot,'off');
            drawnow
            
            app.featureMatrix = [peakOverAvg';adjacentLineRatio(:,1)';adjacentLineRatio(:,2)';adjacentLineRatio(:,3)';adjacentLineRatio(:,4)';...
                lineLength';lineMean;lineVar;lineMoment3;lineMoment4];
            
        end
        
        function results = plotNextCandidate(app)
            for loop = 1:numLines
                % for loopd = 1:1%length(d)
                %   hpAdj(loop,loopd) = plot(app.CandidatePlot,xyAdjForPlot(:,1,loop,loopd), xyAdjForPlot(:,2,loop,loopd),'LineWidth',1,'Color','red');
                % end
                % drawnow;
                %
                % for loopd = 1:1 %length(d)
                %   set(hpAdj(loop,loopd),'visible','off');
                % end
                if isHyphae(loop)
                    hyphCount = hyphCount+1;
                    app.hp(hyphCount) = plot(app.CandidatePlot,app.xyForPlot(:,1,loop), app.xyForPlot(:,2,loop),'LineWidth',2,'Color','green');
                    set(app.hp(hyphCount),'visible','off');
                end
            end
            drawnow
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             WindowAPI(app.UIFigure,'Maximize');
        end

        % Button pushed function: HyphaeButton
        function HyphaeButtonPushed(app, event)
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            path = uigetdir();
            if path ~=0
                app.FolderEditField.Value = path;
                app.folder = path;
                app.fileList = dir(fullfile(app.folder));
                app.fileList = app.fileList(~[app.fileList.isdir]);
                [fold,name,ext] = arrayfun(@(x) fileparts(fullfile(x.folder,x.name)),app.fileList,'UniformOutput',false);
                ext = unique(ext);
                app.DropDown.Items = [app.DropDown.Items, ext'];
                if isempty(ext)
                    uialert(app.UIFigure,'No available files in the selected folder', 'Warning','Icon','info');
                else
                    app.DropDown.Enable = 'on';
                end
            end
        end

        % Button pushed function: NotHyphaeButton
        function NotHyphaeButtonPushed(app, event)
            
        end

        % Button pushed function: GoBack1Button
        function GoBack1ButtonPushed(app, event)
            
        end

        % Value changed function: FolderEditField
        function FolderEditFieldValueChanged(app, event)
            app.folder = app.FolderEditField.Value;
            if ~isfolder(app.folder)
                app.DropDown.Enable = 'off';
                app.HyphaeButton.Enable = 'off';
                app.NotHyphaeButton.Enable = 'off';
                app.GoBack1Button.Enable = 'off';
                uialert(app.UIFigure,'Folder not found','Invalid Folder','Icon','warning');
            else
                app.fileList = dir(fullfile(app.folder));
                app.fileList = app.fileList(~[app.fileList.isdir]);
                [fold,name,ext] = arrayfun(@(x) fileparts(fullfile(x.folder,x.name)),app.fileList,'UniformOutput',false);
                ext = unique(ext);
                app.DropDown.Items = [app.DropDown.Items, ext'];
                app.DropDown.Enable = 'on';
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            selection = uiconfirm(app.UIFigure,'?','Confirm Extention');
            if strcmpi(selection, 'OK')
                app.HyphaeButton.Enable = 'on';
                app.NotHyphaeButton.Enable = 'on';
                app.GoBack1Button.Enable = 'on';
                app.fileList = dir(fullfile(app.folder,['*',value]));
                app.fileNum = 1;
                processImage(app);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StartingImgUIAxes = uiaxes(app.UIFigure);
            app.HyphaeButton = uibutton(app.UIFigure, 'push');
            app.HyphaeButton.ButtonPushedFcn = createCallbackFcn(app, @HyphaeButtonPushed, true);
            app.HyphaeButton.Enable = 'off';
            app.HyphaeButton.Text = 'Hyphae';
            app.NotHyphaeButton = uibutton(app.UIFigure, 'push');
            app.NotHyphaeButton.ButtonPushedFcn = createCallbackFcn(app, @NotHyphaeButtonPushed, true);
            app.NotHyphaeButton.Enable = 'off';
            app.NotHyphaeButton.Text = 'Not Hyphae';
            app.FolderEditFieldLabel = uilabel(app.UIFigure);
            app.FolderEditFieldLabel.Text = 'Folder:';
            app.FolderEditField = uieditfield(app.UIFigure, 'text');
            app.FolderEditField.ValueChangedFcn = createCallbackFcn(app, @FolderEditFieldValueChanged, true);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.GoBack1Button = uibutton(app.UIFigure, 'push');
            app.GoBack1Button.ButtonPushedFcn = createCallbackFcn(app, @GoBack1ButtonPushed, true);
            app.GoBack1Button.Enable = 'off';
            app.GoBack1Button.Text = 'Go Back 1';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Choose Ext'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'Choose Ext';
            app.fibrousStruct = uiaxes(app.UIFigure);
            app.HoughTransform = uiaxes(app.UIFigure);
            app.CandidatePlot = uiaxes(app.UIFigure);
            app.fig3 = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = HyphaeSelection

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end