classdef ImagingPlatformGauss < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PrefrencesMenu            matlab.ui.container.Menu
        ResetPrefrencesMenu       matlab.ui.container.Menu
        TabGroup3                 matlab.ui.container.TabGroup
        DevicesTab                matlab.ui.container.Tab
        MotorControllerPanel      matlab.ui.container.Panel
        InvertXCheckBox           matlab.ui.control.CheckBox
        InvertYCheckBox           matlab.ui.control.CheckBox
        InvertZCheckBox           matlab.ui.control.CheckBox
        XMotorLabel               matlab.ui.control.Label
        XMotorDropDown            matlab.ui.control.DropDown
        TypeDropDownLabel         matlab.ui.control.Label
        XTypeDropDown             matlab.ui.control.DropDown
        RecenterXButton           matlab.ui.control.Button
        RecenterYButton           matlab.ui.control.Button
        RecenterZButton           matlab.ui.control.Button
        TypeDropDown_2Label       matlab.ui.control.Label
        YTypeDropDown             matlab.ui.control.DropDown
        YMotorDropDownLabel       matlab.ui.control.Label
        YMotorDropDown            matlab.ui.control.DropDown
        ZMotorDropDownLabel       matlab.ui.control.Label
        ZMotorDropDown            matlab.ui.control.DropDown
        TypeDropDownLabel_2       matlab.ui.control.Label
        ZTypeDropDown             matlab.ui.control.DropDown
        MotorDropDownLabel        matlab.ui.control.Label
        MotorDropDown             matlab.ui.control.DropDown
        SettingDropDownLabel      matlab.ui.control.Label
        SettingDropDown           matlab.ui.control.DropDown
        SettingEditField          matlab.ui.control.EditField
        ResetAllComPortsButton    matlab.ui.control.Button
        CameraPanel               matlab.ui.container.Panel
        FamilyDropDownLabel       matlab.ui.control.Label
        FamilyDropDown            matlab.ui.control.DropDown
        FormatDropDownLabel       matlab.ui.control.Label
        FormatDropDown            matlab.ui.control.DropDown
        ResetButton               matlab.ui.control.Button
        OpenPreviewWindowButton   matlab.ui.control.Button
        WBRedSpinnerLabel         matlab.ui.control.Label
        WBRedSpinner              matlab.ui.control.Spinner
        WBBlueSpinnerLabel        matlab.ui.control.Label
        WBBlueSpinner             matlab.ui.control.Spinner
        ResetWBButton             matlab.ui.control.Button
        ControlTab                matlab.ui.container.Tab
        XYStagePanel              matlab.ui.container.Panel
        UpButton                  matlab.ui.control.Button
        RightButton               matlab.ui.control.Button
        LeftButton                matlab.ui.control.Button
        DownButton                matlab.ui.control.Button
        DistancemmEditFieldLabel  matlab.ui.control.Label
        DistancemmEditField       matlab.ui.control.NumericEditField
        SaveData                  matlab.ui.container.TabGroup
        ImageTab                  matlab.ui.container.Tab
        SaveImageButton           matlab.ui.control.Button
        SaveDirEditFieldLabel     matlab.ui.control.Label
        SaveDirEditField          matlab.ui.control.EditField
        BrowseButton              matlab.ui.control.Button
        FileNameEditFieldLabel    matlab.ui.control.Label
        FileNameEditField         matlab.ui.control.EditField
        FormatDropDown_2Label     matlab.ui.control.Label
        FormatDropDown_2          matlab.ui.control.DropDown
        SampleSpinnerLabel        matlab.ui.control.Label
        SampleSpinner             matlab.ui.control.Spinner
        ZstackStartButton         matlab.ui.control.Button
        AutoLayoutButton          matlab.ui.control.Button
        StepsImageSpinnerLabel    matlab.ui.control.Label
        StepsImageSpinner         matlab.ui.control.Spinner
        ImagesSpinnerLabel        matlab.ui.control.Label
        ImagesSpinner             matlab.ui.control.Spinner
        LightingTab               matlab.ui.container.Tab
        WhiteLEDsSwitchLabel      matlab.ui.control.Label
        WhiteLEDsSwitch           matlab.ui.control.Switch
        GroupingKnobLabel         matlab.ui.control.Label
        GroupingKnob              matlab.ui.control.DiscreteKnob
        BlueLEDsSwitchLabel       matlab.ui.control.Label
        BlueLEDsSwitch            matlab.ui.control.Switch
        IndividualLevelLabel      matlab.ui.control.Label
        IndividualLevelSlider     matlab.ui.control.Slider
        VideoTab                  matlab.ui.container.Tab
        SaveVideoButton           matlab.ui.control.Button
        BrowseButton_2            matlab.ui.control.Button
        SaveDirEditField_2Label   matlab.ui.control.Label
        SaveDirEditField_2        matlab.ui.control.EditField
        FileNameEditField_2Label  matlab.ui.control.Label
        FileNameEditField_2       matlab.ui.control.EditField
        FileFormatDropDownLabel   matlab.ui.control.Label
        FileFormatDropDown        matlab.ui.control.DropDown
        NamingTab                 matlab.ui.container.Tab
        TextArea_3                matlab.ui.control.TextArea
        LEDsGroup                 matlab.ui.container.TabGroup
        BlueLEDsTab               matlab.ui.container.Tab
        IntensityKnobLabel        matlab.ui.control.Label
        BlueIntensityKnob         matlab.ui.control.Knob
        TopBlueCheckBox           matlab.ui.control.CheckBox
        LeftBlueCheckBox          matlab.ui.control.CheckBox
        BottomBlueCheckBox        matlab.ui.control.CheckBox
        RightBlueCheckBox         matlab.ui.control.CheckBox
        TopLeftBlueCheckBox       matlab.ui.control.CheckBox
        TopRightBlueCheckBox      matlab.ui.control.CheckBox
        BotLeftBlueCheckBox       matlab.ui.control.CheckBox
        BotRightBlueCheckBox      matlab.ui.control.CheckBox
        ValueSpinnerLabel         matlab.ui.control.Label
        BlueValueSpinner          matlab.ui.control.Spinner
        SetBlueLEDsButton         matlab.ui.control.Button
        WhiteLEDsTab              matlab.ui.container.Tab
        SetWhiteLEDsButton        matlab.ui.control.Button
        IntensityKnobLabel_2      matlab.ui.control.Label
        WhiteIntensityKnob        matlab.ui.control.Knob
        ValueSpinnerLabel_2       matlab.ui.control.Label
        WhiteValueSpinner         matlab.ui.control.Spinner
        FocusPanel                matlab.ui.container.Panel
        UpButton_2                matlab.ui.control.Button
        DownButton_2              matlab.ui.control.Button
        AutoButton                matlab.ui.control.Button
        FocusStepEditFieldLabel   matlab.ui.control.Label
        FocusStepEditField        matlab.ui.control.NumericEditField
        SampleInfoTab             matlab.ui.container.Tab
        UITable                   matlab.ui.control.Table
        ExportButton              matlab.ui.control.Button
        SearchTab                 matlab.ui.container.Tab
        UIAxes                    matlab.ui.control.UIAxes
        StartSearchButton         matlab.ui.control.Button
        autoFocusSearchButton     matlab.ui.control.Button
        DisplayImageButton        matlab.ui.control.Button
        LableFileEditFieldLabel   matlab.ui.control.Label
        LableFileEditField        matlab.ui.control.EditField
        BrowseButton_3            matlab.ui.control.Button
        ReadLabelsButton          matlab.ui.control.Button
        StackImagesAtEndCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        xCenters = [0,460,920,2760,3220,3680,4140,4600,5060,5520,5980,6440,6900,7360,7820,8280,8740,9200];
        yCenters = [0,2340,4680,7020,9360,11700,14040,16380,18720,21060,23400,25740,28080,30420,32760,35100,37440];
        Xmotor
        Ymotor
        Zmotor
        VidObj
        VidSrc
        Ymm2step = 200 % number of steps needed to move Y stage 1 mm
        Xmm2step = 40 % number of steps needed to move X stage 1 mm
        Zmm2Step = 40 % number of steps needed to move Z stage 1 mm
        htop
        SaveDir % Save Directory
        SampleCount = 1 % count of files using using current save name
        ActiveCom
        initWBRed = 673 % Description
        initWBBlue = 663 % Description
        hyphaeCnt % Description
        y1 % Description
        currentSearch = 1; % Description
        xLoc = 0 % Description
        yLoc = 0 % Description
        Audio
        nikonCam % Description
        labels = []
        CurLable % Description
    end
    
    methods (Access = private)
        
        function sPort = startSerial(app,Port,Type,name)
            serialInfo = instrhwinfo('serial');
            if strcmpi(Type,'Nanotech')
                sPort = serial(Port,'BaudRate',115200,'RecordDetail','verbose','RecordMode','append','RecordName',[name,'motor.txt']);
                str = strfind(serialInfo.AvailableSerialPorts,Port);
                if any([str{:}])
                    fopen(sPort);
                    sPort.Status;
                    set(sPort,'Terminator','CR');
                    record(sPort);
                    serialCom.NanotecInit(sPort);
                else
                    uialert(app.UIFigure,'Port was not valid, please check to make sure it is available, and not in use','Error Connecting to Motor');
                    sPort = 0;
                end
            elseif strcmpi(Type,'MSP432')
                sPort = serial(Port,'BaudRate',19200,'RecordDetail','verbose','RecordMode','append','RecordName',[name,'motor.txt']);
                str = strfind(serialInfo.AvailableSerialPorts,Port);
                if any([str{:}])
                    fopen(sPort);
                    sPort.Status;
                    set(sPort,'Terminator','CR');
                    record(sPort);
                    serialCom.MSP432Init(sPort);
                    sPort.Timeout = 30;
                else
                    uialert(app.UIFigure,'Port was not valid, please check to make sure it is available, and not in use','Error Connecting to Motor');
                    sPort = 0;
                end
            else
                uialert(app.UIFigure,'Unknown error with device model. Please restart program.','Unknown error')
                sPort = -1;
            end
            
        end
        
        function writeImage(app,fileName)
            saveExt = app.FormatDropDown_2.Value;
            saveLoc = fullfile(app.SaveDir, [fileName, saveExt(2:end)]);
            file=java.io.File(saveLoc);
            if ~file.exists()
                if app.nikonCam
                    camera.saveNikonImg(saveLoc)
                else
                    imwrite(CurImg(app), saveLoc);
                end
            else
                i = 1;
                while file.exists()
                    saveLoc = fullfile(app.SaveDir, [fileName,'(',num2str(i),')', saveExt(2:end)]);
                    file=java.io.File(saveLoc);
                    i=i+1;
                end
                if app.nikonCam
                    camera.saveNikonImg(path)
                else
                    imwrite(CurImg(app), saveLoc);
                end
            end
        end
        
    end
    
    methods (Access = public)
        function [results,lightStr] = activeBlueLights(app,lightStr)
            val3 = app.TopBlueCheckBox.Value; % val 3
            val4 = app.LeftBlueCheckBox.Value; % val 4
            val2 = app.BottomBlueCheckBox.Value; % val 2
            val1 = app.RightBlueCheckBox.Value;% val 1
            % note these may need to change
            val5 = app.TopLeftBlueCheckBox.Value; % val 5
            val6 = app.TopRightBlueCheckBox.Value; % val 6
            val7 = app.BotLeftBlueCheckBox.Value; % val 7
            val8 = app.BotRightBlueCheckBox.Value;% val 8
            results = [val1,val2,val3,val4,val5,val6,val7,val8];
            locations = {'R';'B';'T';'L';'TL';'TR';'BL';'BR'};
            if nargout ==2
                for i = 1:length(results)
                    if results(i)
                        lightStr = sprintf([lightStr,'%s%d'],locations{i}, app.BlueValueSpinner.Value);
                    end
                end
            end
        end
        
        function results = curX(app)
            output = serialCom.writeToSerial(app.Xmotor,'C');
            results = str2double(output(3:end));
        end
        
        function results = curY(app,varargin)
            output = serialCom.writeToSerial(app.Ymotor,'C');
            results = str2double(output(3:end));
        end
        
        function results = curZ(app)
            output = serialCom.writeToSerial(app.Zmotor,'C');
            results = str2double(output(3:end));
        end
        
        function newName = SaveName(app,fileName,lightStr)
            if isempty(lightStr)
                lightStr = 'L0';
            end
            exp ={'(?i:\[DATE\])';...
                '(?i:\[TIME\])';...
                '(?i:\[LOC\])';...
                '(?i:\[LABEL\])';...
                '(?i:\[(NUM((B)?(ER)?)?|#)\])';...
                '(?i:\[LIGHT\])'};
            rep = { datestr(now,'mmmddyy');datestr(now,'HHMMSS');sprintf('(%d,%d,%d)',curX(app),curY(app),curZ(app));app.CurLable;sprintf('(%d)',app.SampleCount);lightStr};
            
            newName = regexprep(fileName,exp,rep);
        end
        
        function results = CurImg(app)
            if app.nikonCam
                results = camera.takeNikonImg();
            else
                results = getsnapshot(app.VidObj);
            end
        end
        
        function enableCarrythrough(app)
            % XMOTOR
            XMotor = app.XMotorDropDown.Value;
            if ~strcmpi(XMotor, 'not active')
                app.XTypeDropDown.Enable = 'on';
            else
                app.XMotorDropDown.Enable='on';
                app.XTypeDropDown.Enable = 'off';
                app.XTypeDropDown.Value = 'Select Type';
            end
            XType = app.XTypeDropDown.Value;
            if ~strcmpi(XType, 'Select Type')
                app.InvertXCheckBox.Enable = 'on';
                app.RecenterXButton.Enable = 'on';
                app.RightButton.Enable = 'on';
                app.LeftButton.Enable = 'on';
                app.XTypeDropDown.Enable = 'off';
                app.XMotorDropDown.Enable='off';
            else
                app.InvertXCheckBox.Enable = 'off';
                app.RecenterXButton.Enable = 'off';
                app.RightButton.Enable = 'off';
                app.LeftButton.Enable = 'off';
            end
            % YMOTOR
            YMotor = app.YMotorDropDown.Value;
            if ~strcmpi(YMotor, 'not active')
                app.YTypeDropDown.Enable = 'on';
            else
                app.YMotorDropDown.Enable='on';
                app.YTypeDropDown.Enable = 'off';
                app.YTypeDropDown.Value = 'Select Type';
            end
            YType = app.YTypeDropDown.Value;
            if ~strcmpi(YType, 'Select Type')
                app.InvertYCheckBox.Enable = 'on';
                app.RecenterYButton.Enable = 'on';
                app.UpButton.Enable = 'on';
                app.DownButton.Enable = 'on';
                app.YTypeDropDown.Enable = 'off';
                app.YMotorDropDown.Enable='off';
            else
                app.InvertYCheckBox.Enable = 'off';
                app.RecenterYButton.Enable = 'off';
                app.UpButton.Enable = 'off';
                app.DownButton.Enable = 'off';
            end
            %ZMOTOR
            ZMotor = app.ZMotorDropDown.Value;
            if ~strcmpi(ZMotor, 'not active')
                app.ZTypeDropDown.Enable = 'on';
            else
                app.ZMotorDropDown.Enable='on';
                app.ZTypeDropDown.Enable = 'off';
                app.ZTypeDropDown.Value = 'Select Type';
            end
            ZType = app.ZTypeDropDown.Value;
            if ~strcmpi(ZType, 'Select Type')
                app.InvertZCheckBox.Enable = 'on';
                app.RecenterZButton.Enable = 'on';
                app.UpButton_2.Enable = 'on';
                app.DownButton_2.Enable = 'on';
                if strcmpi(app.FamilyDropDown.Value,'Select Family') || strcmpi(app.FormatDropDown,'Select Format')
                    app.AutoButton.Enable = 'off';
                    app.AutoLayoutButton.Enable = 'off';
                    app.ZstackStartButton.Enable = 'off';
                    app.SaveImageButton.Enable = 'off';
                else
                    app.AutoButton.Enable = 'on';
                    app.AutoLayoutButton.Enable = 'on';
                    app.ZstackStartButton.Enable = 'on';
                    app.SaveImageButton.Enable = 'on';
                end
                app.ZTypeDropDown.Enable = 'off';
                app.ZMotorDropDown.Enable='off';
            else
                app.InvertZCheckBox.Enable = 'off';
                app.RecenterZButton.Enable = 'off';
                app.UpButton_2.Enable = 'off';
                app.DownButton_2.Enable = 'off';
                app.AutoButton.Enable = 'off';
                app.AutoLayoutButton.Enable = 'off';
                app.ZstackStartButton.Enable = 'off';
                app.SaveImageButton.Enable = 'off';
            end
            %MOTORSETTING
            list = {'Select Motor'};
            if ~strcmpi(app.XTypeDropDown.Value,'Select Type')
                list = [list,{'X Motor'}];
            end
            if ~strcmpi(app.YTypeDropDown.Value,'Select Type')
                list = [list,{'Y Motor'}];
            end
            if ~strcmpi(app.ZTypeDropDown.Value,'Select Type')
                list = [list,{'Z Motor'}];
            end
            app.MotorDropDown.Items= list;
            if length(list) == 1
                app.MotorDropDown.Enable = 'off';
                app.SettingDropDown.Enable = 'off';
            else
                app.MotorDropDown.Enable = 'on';
            end
            if ~strcmpi(app.MotorDropDown.Value,'Select Motor')
                app.SettingDropDown.Enable = 'on';
                SettingEditFieldValueChanged(app);
            else
                app.SettingDropDown.Enable = 'off';
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            serialCom.endAllSerial;
            
            clear -regexp [^(app)];
            list = seriallist;
            app.XMotorDropDown.Items = [app.XMotorDropDown.Items,list];
            app.YMotorDropDown.Items = [app.YMotorDropDown.Items,list];
            app.ZMotorDropDown.Items = [app.ZMotorDropDown.Items,list];
            cameraInfo = imaqhwinfo;
            app.FamilyDropDown.Items = ['Select Family',cameraInfo.InstalledAdaptors,'Nikon'];
            WarnWave = [sin(1:.6:400), sin(1:.7:400), sin(1:.4:400)];
            app.Audio = audioplayer(WarnWave, 22050);
            app.ActiveCom = 0;
            prefStruct = getpref('ImagingPlatformGauss');
            if isempty(prefStruct)
                addpref('ImagingPlatformGauss','version',{'1.1','beta'});
                addpref('ImagingPlatformGauss','xMotor',struct('port',[],'type',[],'invert',[]));
                addpref('ImagingPlatformGauss','yMotor',struct('port',[],'type',[],'invert',[]));
                addpref('ImagingPlatformGauss','zMotor',struct('port',[],'type',[],'invert',[],'focusStep',[],'imageStep',[],'imageCount',[]));
                addpref('ImagingPlatformGauss','camera',struct('family',[],'format',[],'WBred',[],'WBblue',[]));
                addpref('ImagingPlatformGauss','fileSettigs',struct('folder',[],'format','*.tiff'));
                addpref('ImagingPlatformGauss','lighting',struct('grouping','All LEDs','white','On','blue','On','active',[],'level',struct('white',0,'blue',0,'individual',0)));
                addpref('ImagingPlatformGauss','saveSetup','ask');
                addpref('ImagingPlatformGauss','Helicon',struct('Loc',[],'saveLoc','ask','heliconAtEnd',1));
            else
                if ~isempty(prefStruct.xMotor.port) && ~strcmpi(prefStruct.saveSetup,'never')
                    if any(strcmpi(prefStruct.xMotor.port,app.XMotorDropDown.Items))
                        app.XMotorDropDown.Value = prefStruct.xMotor.port;
                        app.XTypeDropDown.Value = prefStruct.xMotor.type;
                        app.InvertXCheckBox.Value = prefStruct.xMotor.invert;
                    end
                    if any(strcmpi(prefStruct.yMotor.port,app.YMotorDropDown.Items))
                        app.YMotorDropDown.Value = prefStruct.yMotor.port;
                        app.YTypeDropDown.Value = prefStruct.yMotor.type;
                        app.InvertYCheckBox.Value = prefStruct.yMotor.invert;
                    end
                    if any(strcmpi(prefStruct.zMotor.port,app.ZMotorDropDown.Items))
                        app.ZMotorDropDown.Value = prefStruct.zMotor.port;
                        app.ZTypeDropDown.Value = prefStruct.zMotor.type;
                        app.InvertZCheckBox.Value = prefStruct.zMotor.invert;
                    end
                    TypeDropDownValueChanged(app);
                    try
                        app.FamilyDropDown.Value = prefStruct.camera.family;
                        FamilyDropDownValueChanged(app);
                        enableCarrythrough(app);
                        app.FormatDropDown.Value = prefStruct.camera.format;
                        FormatDropDownValueChanged(app);
                        app.WBRedSpinner.Value = prefStruct.camera.WBred;
                        app.WBBlueSpinner.Value = prefStruct.camera.WBblue;
                        if ishandle(app.htop)
                            close(app.htop);
                            drawnow;
                        end
                    catch err
                        uialert(app.UIFigure,'Camera Settings could not be replicated from last session. Resetting camera settings.','Camera Setup','Icon','Warning');
                        ResetButtonPushed(app);
                    end
                    app.WhiteIntensityKnob.Value = prefStruct.lighting.level.white;
                    app.BlueIntensityKnob.Value = prefStruct.lighting.level.blue;
                    app.IndividualLevelSlider.Value = prefStruct.lighting.level.individual;
                    app.GroupingKnob.Value = prefStruct.lighting.grouping;
                    app.WhiteLEDsSwitch.Value = prefStruct.lighting.white;
                    app.BlueLEDsSwitch.Value = prefStruct.lighting.blue;
                    app.SaveDirEditField.Value = prefStruct.fileSettigs.folder;
                    app.FormatDropDown_2.Value = prefStruct.fileSettigs.format;
                    app.FocusStepEditField.Value = prefStruct.zMotor.focusStep;
                    app.StepsImageSpinner.Value = prefStruct.zMotor.imageStep;
                    app.ImagesSpinner.Value = prefStruct.zMotor.imageCount;
                    blueLEDs = prefStruct.lighting.active;
                    app.TopBlueCheckBox.Value = blueLEDs(3); % val 3
                    app.LeftBlueCheckBox.Value = blueLEDs(4); % val 4
                    app.BottomBlueCheckBox.Value = blueLEDs(2); % val 2
                    app.RightBlueCheckBox.Value = blueLEDs(1);% val 1
                    % note these may need to change
                    app.TopLeftBlueCheckBox.Value = blueLEDs(5); % val 5
                    app.TopRightBlueCheckBox.Value = blueLEDs(6); % val 6
                    app.BotLeftBlueCheckBox.Value = blueLEDs(7); % val 7
                    app.BotRightBlueCheckBox.Value = blueLEDs(8);% val 8
                    enableCarrythrough(app);
                    if isfield(prefStruct.Helicon,'heliconAtEnd')
                        app.StackImagesAtEndCheckBox.Value = prefStruct.Helicon.heliconAtEnd;
                    end
                    if any(strcmpi(prefStruct.yMotor.port,app.YMotorDropDown.Items))
                        SetBlueLEDsButtonPushed(app);
                        SetWhiteLEDsButtonPushed(app);
                    end
                end
                heliconPref = getpref('ImagingPlatformGauss','Helicon',struct('Loc',[],'saveLoc','ask'));
                if isempty(heliconPref.Loc)
                    [FileName,PathName,~] = uigetfile('*.exe','Where is the Helicon.exe file?');
                    path = fullfile(PathName,FileName);
                    heliconPref.Loc = regexprep(path,'\\','\\\\');
                    setpref('ImagingPlatformGauss','Helicon',heliconPref);
                else
                    if strcmpi(heliconPref.saveLoc,'ask')
                        [FileName,PathName,~] = uigetfile('*.exe','Where is the Helicon.exe file?');
                        path = fullfile(PathName,FileName);
                        heliconPref.Loc = regexprep(path,'\\','\\\\');
                        setpref('ImagingPlatformGauss','Helicon',heliconPref);
                    end
                end
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            if isempty(app.SaveDirEditField.Value) || isempty(app.SaveDirEditField_2.Value) %TODO finish updateing video tab with location.
                userProfile = getenv('USERPROFILE');
                app.SaveDir = uigetdir(fullfile(userProfile),'Select Where to Save Images');
            else
                app.SaveDir = uigetdir(app.SaveDir,'Select Where to Save Images');
            end
            if ~(app.SaveDir == 0)
                app.SaveDirEditField.Value = app.SaveDir;
                if ~isempty(app.VidObj)
                    app.SaveImageButton.Enable = 'on';
                end
            end
        end

        % Button pushed function: DownButton, LeftButton, 
        % RightButton, UpButton
        function XYMoveButtonPushed(app, event)
            mapNeg = [1,-1];
            switch event.Source
                case app.DownButton
                    dist = abs(app.Ymm2step*app.DistancemmEditField.Value)*mapNeg(app.InvertYCheckBox.Value+1);
                    app.ActiveCom = true;
                    serialCom.stepMove(app.Ymotor ,dist);
                    app.ActiveCom = false;
                case app.UpButton
                    dist = abs(app.Ymm2step*app.DistancemmEditField.Value)*mapNeg(app.InvertYCheckBox.Value+1);
                    app.ActiveCom = true;
                    serialCom.stepMove(app.Ymotor ,-dist);
                    app.ActiveCom = false;
                case app.LeftButton
                    dist = abs(app.Xmm2step*app.DistancemmEditField.Value)*mapNeg(app.InvertXCheckBox.Value+1);
                    app.ActiveCom = true;
                    serialCom.stepMove(app.Xmotor ,-dist);
                    app.ActiveCom = false;
                case app.RightButton
                    dist = abs(app.Xmm2step*app.DistancemmEditField.Value)*mapNeg(app.InvertXCheckBox.Value+1);
                    app.ActiveCom = true;
                    serialCom.stepMove(app.Xmotor ,dist);
                    app.ActiveCom = false;
                otherwise
            end
        end

        % Button pushed function: OpenPreviewWindowButton
        function OpenPreviewWindowButtonPushed(app, event)
            if (isempty(app.htop))||(~ishandle(app.htop))
                if app.nikonCam == 0
                    h = preview(app.VidObj);
                    app.htop=ancestor(h,{'figure'},'toplevel');
                else
                    app.htop = figure;
                    camera.niconPreview(app.htop);
                end
            end
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            if ishandle(app.htop)
                close(app.htop);
                drawnow;
            end
            
            saveFileName = app.FileNameEditField.Value;
            activeLights = activeBlueLights(app);
            
            if strcmpi(app.GroupingKnob.Value,'All LEDs')
                if (app.WhiteValueSpinner.Value ~= 0) && (strcmpi(app.WhiteLEDsSwitch.Value,'On'))
                    lightStr = sprintf('W%d',app.WhiteValueSpinner.Value);
                else
                    lightStr = '';
                    serialCom.writeToSerial(app.Ymotor,'LA000:000'); %Turn white off
                end
                if (app.BlueValueSpinner.Value ~= 0) && (strcmpi(app.BlueLEDsSwitch.Value,'On'))
                    [~,lightStr] = activeBlueLights(app,lightStr);
                else
                    % Turn Blue off
                    serialCom.writeToSerial(app.Ymotor,'LB000:000');
                end
                newName = SaveName(app,saveFileName,lightStr);
                writeImage(app,newName);
                
            elseif strcmpi(app.GroupingKnob.Value,'Color Group')
                if (app.WhiteValueSpinner.Value ~= 0) && (strcmpi(app.WhiteLEDsSwitch.Value,'On'))
                    serialCom.writeToSerial(app.Ymotor,'LB000:000');
                    lightStr = sprintf('W%d',app.WhiteValueSpinner.Value);
                    newName = SaveName(app,saveFileName,lightStr);
                    writeImage(app,newName);
                end
                lightStr = '';
                decvec = bi2de(activeLights);
                val=0;
                
                fmt=num2str(3,'%%0%dg');
                decvec=sprintf(fmt,decvec);
                val =sprintf(fmt,val);
                serialCom.writeToSerial(app.Ymotor,['LA',decvec,':',val]);%Turn white off
                SetBlueLEDsButtonPushed(app);
                if (app.BlueValueSpinner.Value ~= 0) && (strcmpi(app.BlueLEDsSwitch.Value,'On'))
                    [~,lightStr] = activeBlueLights(app,lightStr);
                    newName = SaveName(app,saveFileName,lightStr);
                    writeImage(app,newName);
                end
                
            elseif strcmpi(app.GroupingKnob.Value,'Individual')
                if (app.WhiteValueSpinner.Value ~= 0) && (strcmpi(app.WhiteLEDsSwitch.Value,'On'))
                    lightStr = sprintf('W%d',app.WhiteValueSpinner.Value);
                    serialCom.writeToSerial(app.Ymotor,'LB000:000'); %Turn blue off
                    newName = SaveName(app,saveFileName,lightStr);
                    writeImage(app,newName);
                end
                lightStr = '';
                if (app.IndividualLevelSlider.Value ~= 0) && (strcmpi(app.BlueLEDsSwitch.Value,'On'))
                    serialCom.writeToSerial(app.Ymotor,'LA000:000'); %Turn white off
                    locations = {'R';'B';'T';'L';'TL';'TR';'BL';'BR'};
                    for i = 1:length(activeLights)
                        if activeLights(i)
                            arr = zeros(1,8);
                            arr(i) = 1;
                            decvec = bi2de(arr);
                            val=ceil((255/100)*app.IndividualLevelSlider.Value);
                            
                            fmt=num2str(3,'%%0%dg');
                            decvec=sprintf(fmt,decvec);
                            val =sprintf(fmt,val);
                            serialCom.writeToSerial(app.Ymotor,['LB',decvec,':',val]);
                            lightStr = sprintf([lightStr,'%s%d'],locations{i}, app.IndividualLevelSlider.Value);
                            newName = SaveName(app,saveFileName,lightStr);
                            writeImage(app,newName);
                            lightStr = '';
                        end
                    end
                end
            end
            % restore Lighting to previous
            SetWhiteLEDsButtonPushed(app);
            SetBlueLEDsButtonPushed(app);
        end

        % Button pushed function: SetBlueLEDsButton
        function SetBlueLEDsButtonPushed(app, event)
            decvec = bi2de(activeBlueLights(app));
            val=ceil((255/100)*app.BlueIntensityKnob.Value);
            fmt=num2str(3,'%%0%dg');
            decvec=sprintf(fmt,decvec);
            val =sprintf(fmt,val);
            if ~strcmpi(app.YMotorDropDown.Value,'Not Active')
                i=0;
                while logical(app.ActiveCom)&&(i<10000)
                    i=i+1;
                end
                app.ActiveCom = true;
                serialCom.writeToSerial(app.Ymotor,['LB',decvec,':',val]);
                app.ActiveCom = false;
            else
                uialert(app.UIFigure,'No LED Controller','No LED Controller','Icon','warn')
            end
        end

        % Button pushed function: SetWhiteLEDsButton
        function SetWhiteLEDsButtonPushed(app, event)
            decvec = bi2de(activeBlueLights(app));
            val=ceil((255/100)*app.WhiteIntensityKnob.Value);
            fmt=num2str(3,'%%0%dg');
            decvec=sprintf(fmt,decvec);
            val =sprintf(fmt,val);
            if ~strcmpi(app.YMotorDropDown.Value,'Not Active')
                i=0;
                while logical(app.ActiveCom)&&(i<10000)
                    i=i+1;
                end
                app.ActiveCom = true;
                serialCom.writeToSerial(app.Ymotor,['LA',decvec,':',val]);
                app.ActiveCom = false;
            else
                uialert(app.UIFigure,'No LED Controller','No LED Controller','Icon','warn')
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            [selectedButton, ~] = uigetpref('ImagingPlatformGauss','saveSetup','Save Setting on Close?',...
                {'Do you want to save your settings before closing?'},...
                {'always','never';'Yes','No'},...        % Values and button labels
                'DefaultButton','always');               % Default choice
            helicon = getpref('ImagingPlatformGauss','Helicon');
            switch selectedButton
                case 'always'
                    setpref('ImagingPlatformGauss','xMotor',struct('port',app.XMotorDropDown.Value,...
                        'type',app.XTypeDropDown.Value,...
                        'invert',app.InvertXCheckBox.Value));
                    setpref('ImagingPlatformGauss','yMotor',struct('port',app.YMotorDropDown.Value,...
                        'type',app.YTypeDropDown.Value,...
                        'invert',app.InvertYCheckBox.Value));
                    setpref('ImagingPlatformGauss','zMotor',struct('port',app.ZMotorDropDown.Value,...
                        'type',app.ZTypeDropDown.Value,...
                        'invert',app.InvertZCheckBox.Value,...
                        'focusStep',app.FocusStepEditField.Value,...
                        'imageStep',app.StepsImageSpinner.Value,...
                        'imageCount',app.ImagesSpinner.Value));
                    setpref('ImagingPlatformGauss','camera',struct('family',app.FamilyDropDown.Value,...
                        'format',app.FormatDropDown.Value,....
                        'WBred',app.WBRedSpinner.Value,...
                        'WBblue',app.WBBlueSpinner.Value));
                    setpref('ImagingPlatformGauss','fileSettigs',struct('folder',app.SaveDirEditField.Value,...
                        'format',app.FormatDropDown_2.Value));
                    setpref('ImagingPlatformGauss','lighting',struct('grouping',app.GroupingKnob.Value,...
                        'white',app.WhiteLEDsSwitch.Value,...
                        'blue',app.BlueLEDsSwitch.Value,...
                        'active',activeBlueLights(app),...
                        'level',struct('white',app.WhiteIntensityKnob.Value,...
                        'blue',app.BlueIntensityKnob.Value,...
                        'individual',app.IndividualLevelSlider.Value)));
                    setpref('ImagingPlatformGauss','Helicon',struct('Loc',helicon.Loc,...
                        'saveLoc','always'));
                case 'never'
                    setpref('ImagingPlatformGauss','xMotor',struct('port',[],'type',[],'invert',[]));
                    setpref('ImagingPlatformGauss','yMotor',struct('port',[],'type',[],'invert',[]));
                    setpref('ImagingPlatformGauss','zMotor',struct('port',[],'type',[],'invert',[]));
                    setpref('ImagingPlatformGauss','camera',struct('family',[],'format',[]));
                    setpref('ImagingPlatformGauss','fileSettigs',struct('folder',[],'format','*.tiff'));
                    setpref('ImagingPlatformGauss','lighting',struct('grouping','All LEDs','white','On','blue','On','level',struct('white',0,'blue',0,'individual',0)));
                    setpref('ImagingPlatformGauss','Helicon',struct('Loc',helicon.Loc,...
                        'saveLoc','ask'));
                otherwise
                    
            end
            if ishandle(app.htop)
                close(app.htop);
                drawnow;
            end
            i = 0;
            while logical(app.ActiveCom)&&(i<10000)
                i= i+1;
            end
            disp(i);
            try
                if ~strcmpi(app.YMotorDropDown.Value,'Not Active')
                    app.ActiveCom = true;
                    serialCom.writeToSerial(app.Ymotor,'LA000:000');
                    serialCom.writeToSerial(app.Ymotor,'LB000:000');
                    app.ActiveCom = false;
                end
            catch
            end
            serialCom.endAllSerial();
            delete(app);
        end

        % Callback function: BlueIntensityKnob, WhiteIntensityKnob
        function IntensityKnobValueChanged(app, event)
            white = app.WhiteIntensityKnob.Value;
            blue = app.BlueIntensityKnob.Value;
            app.WhiteValueSpinner.Value = white;
            app.BlueValueSpinner.Value = blue;
        end

        % Callback function: BlueValueSpinner, WhiteValueSpinner
        function IntensitySpinnerValueChanged(app, event)
            blue = app.BlueValueSpinner.Value;
            white = app.WhiteValueSpinner.Value;
            app.BlueIntensityKnob.Value = blue;
            app.WhiteIntensityKnob.Value = white;
        end

        % Value changed function: XMotorDropDown, YMotorDropDown, 
        % ZMotorDropDown
        function MotorSourceValueChanged(app, event)
            enableCarrythrough(app)
        end

        % Value changed function: XTypeDropDown, YTypeDropDown, 
        % ZTypeDropDown
        function TypeDropDownValueChanged(app, event)
            XType = app.XTypeDropDown.Value;
            YType = app.YTypeDropDown.Value;
            ZType = app.ZTypeDropDown.Value;
            if ~strcmpi(XType, 'select type') && isempty(app.Xmotor)
                msg = sprintf('%s is connected to a %s controlled motor?',app.XMotorDropDown.Value,XType);
                title = 'Confirm Settings.';
                selection = uiconfirm(app.UIFigure,msg,title,...
                    'Options',{'Yes','No'},...
                    'DefaultOption',2,'CancelOption',2);
                if strcmpi(selection,'Yes')
                    sPort = startSerial(app,app.XMotorDropDown.Value,XType,'X');
                    if sPort == 0
                        app.XTypeDropDown.Value = 'Select Type';
                    elseif sPort == -1
                        selection = uiconfirm(app.UIFigure, 'Close Program?','Confirm Close');
                        if strcmpi(selection,'ok')
                            close(app);
                        end
                    else
                        app.Xmotor = sPort;
                        
                    end
                else
                    app.XTypeDropDown.Value = 'Select Type';
                end
            end
            if ~strcmpi(YType, 'select type') && isempty(app.Ymotor)
                msg = sprintf('%s is connected to a %s controlled motor?',app.YMotorDropDown.Value,YType);
                title = 'Confirm Settings.';
                selection = uiconfirm(app.UIFigure,msg,title,...
                    'Options',{'Yes','No'},...
                    'DefaultOption',2,'CancelOption',2);
                if strcmpi(selection,'Yes')
                    sPort = startSerial(app,app.YMotorDropDown.Value,YType,'Y');
                    if sPort == 0
                        app.YTypeDropDown.Value = 'Select Type';
                    elseif sPort == -1
                        selection = uiconfirm(app.UIFigure, 'Close Program?','Confirm Close');
                        if strcmpi(selection,'ok')
                            close(app);
                        end
                    else
                        app.Ymotor = sPort;
                    end
                else
                    app.YTypeDropDown.Value = 'Select Type';
                end
            end
            if ~strcmpi(ZType, 'select type') && isempty(app.Zmotor)
                msg = sprintf('%s is connected to a %s controlled motor?',app.ZMotorDropDown.Value,ZType);
                title = 'Confirm Settings.';
                selection = uiconfirm(app.UIFigure,msg,title,...
                    'Options',{'Yes','No'},...
                    'DefaultOption',2,'CancelOption',2);
                if strcmpi(selection,'Yes')
                    sPort = startSerial(app,app.ZMotorDropDown.Value,ZType,'Z');
                    if sPort == 0
                        app.ZTypeDropDown.Value = 'Select Type';
                    elseif sPort == -1
                        selection = uiconfirm(app.UIFigure, 'Close Program?','Confirm Close');
                        if strcmpi(selection,'ok')
                            close(app);
                        end
                    else
                        app.Zmotor = sPort;
                        
                    end
                else
                    app.ZTypeDropDown.Value = 'Select Type';
                end
            end
            enableCarrythrough(app);
        end

        % Value changed function: FamilyDropDown
        function FamilyDropDownValueChanged(app, event)
            value = app.FamilyDropDown.Value;
            if ~strcmpi(value, 'Select Family')&&~strcmpi(value, 'Nikon')
                devInfo = imaqhwinfo(value);
                if ~isempty(devInfo.DeviceInfo)
                    app.FormatDropDown.Items = ['Select Format',devInfo.DeviceInfo.SupportedFormats];
                    app.FormatDropDown.Enable = 'on';
                else
                    uialert(app.UIFigure,'No camera is attached to the computer','No Camera');
                end
            elseif ~strcmpi(value, 'Select Family')&& strcmpi(value, 'Nikon')
                app.FormatDropDown.Items = {'Select Format','Open Camera (TIFF)'};
                app.FormatDropDown.Enable = 'on';
                
            else
                app.FormatDropDown.Enable = 'off';
                app.OpenPreviewWindowButton.Enable='off';
            end
            enableCarrythrough(app);
        end

        % Value changed function: FormatDropDown
        function FormatDropDownValueChanged(app, event)
            value = app.FormatDropDown.Value;
            cameraFamily = app.FamilyDropDown.Value;
            if strcmpi(value, 'Open Camera (TIFF)')&& strcmpi(cameraFamily, 'Nikon')
                app.nikonCam = D850_driver_v2('open');
                if ~app.nikonCam
                    uialert(app.UIFigure,'There is no Nikon camera connected.','Nikon Error')
                    app.FormatDropDown.Items = {'Select Format','Open Camera (TIFF)'};
                    app.FormatDropDown.Value = 'Select Format';
                    app.VidObj = 'nikonCam';
                    app.VidSrc = 'nikonCam';
                    app.WBRedSpinner.Value = 0;
                    app.WBBlueSpinner.Value = 0;
                    app.WBRedSpinner.Enable ='off';
                    app.WBBlueSpinner.Enable = 'off';
                    app.ResetWBButton.Enable = 'off';
                    app.OpenPreviewWindowButton.Enable='off';
                    app.ResetButton.Enable = 'off';
                    app.FamilyDropDown.Enable = 'on';
                    app.FormatDropDown.Enable = 'on';
                else
                    app.VidObj = -1;
                    app.VidSrc = -1;
                    app.WBRedSpinner.Enable ='off';
                    app.WBBlueSpinner.Enable = 'off';
                    app.ResetWBButton.Enable = 'off';
                    app.OpenPreviewWindowButton.Enable='on';
                    app.ResetButton.Enable = 'on';
                    app.FamilyDropDown.Enable = 'off';
                    app.FormatDropDown.Enable = 'off';
                    try
                        D850_driver_v2('live_off');
                    catch
                    end
                    D850_driver_v2('close');
                    app.htop = figure;
                    camera.niconPreview(app.htop);
                end
            elseif ~strcmpi(value, 'Select Format')
                vid = videoinput(app.FamilyDropDown.Value,1,value);
                if isvalid(vid)
                    if strcmpi(app.FamilyDropDown.Value,'pointgrey')
                        src = getselectedsource(vid);
                        %                         get(src);
                        vid.FramesPerTrigger = 1;
                        src.WhiteBalanceRBMode = 'Manual';
                        src.WhiteBalanceRB = [app.initWBRed app.initWBBlue];
                        src.Saturation = 175;
                        src.GainMode  = 'Manual';
                        src.Gain = 0.301;
                        src.ShutterMode = 'Manual';
                        src.Shutter = 132;
                        src.FrameRateMode = 'Auto';
                        triggerconfig(vid, 'manual');
                    end
                    app.VidObj = vid;
                    app.VidSrc = src;
                    app.WBRedSpinner.Value = app.initWBRed;
                    app.WBBlueSpinner.Value = app.initWBBlue;
                    app.WBRedSpinner.Enable ='on';
                    app.WBBlueSpinner.Enable = 'on';
                    app.ResetWBButton.Enable = 'on';
                    app.OpenPreviewWindowButton.Enable='on';
                    app.ResetButton.Enable = 'on';
                    h = preview(app.VidObj);
                    app.htop=ancestor(h,{'figure'},'toplevel');
                    app.FamilyDropDown.Enable = 'off';
                    app.FormatDropDown.Enable = 'off';
                end
            end
            enableCarrythrough(app);
        end

        % Value changed function: MotorDropDown
        function MotorDropDownValueChanged(app, event)
            value = app.MotorDropDown.Value;
            if ~strcmpi(value,'Select Motor')
                app.SettingDropDown.Enable = 'on';
                app.SettingEditField.Enable = 'on';
                if strcmpi(value, 'X Motor')
                    result=serialCom.writeToSerial(app.Xmotor,'C');
                elseif strcmpi(value, 'Y Motor')
                    result=serialCom.writeToSerial(app.Ymotor,'C');
                elseif strcmpi(value, 'Z Motor')
                    result=serialCom.writeToSerial(app.Zmotor,'C');
                end
                expression = '1(([:]accel)|([:]decel)|(u)|(o)|(C)|(\$))';
                replace = '';
                dispresult = regexprep(result,expression,replace);
                app.SettingEditField.Value= dispresult;
                app.SettingDropDown.Value = 'Current Location';
            else
                app.SettingDropDown.Enable = 'off';
                app.SettingEditField.Enable = 'off';
            end
        end

        % Value changed function: SettingDropDown
        function SettingDropDownValueChanged(app, event)
            value = app.SettingDropDown.Value;
            Motor = app.MotorDropDown.Value;
            switch value
                case 'Accel Ramp'
                    str = ':accel';
                    app.SettingEditField.Editable = 'on';
                case 'Decel Ramp'
                    str = ':decel';
                    app.SettingEditField.Editable = 'on';
                case 'Init Frequency'
                    str = 'u';
                    app.SettingEditField.Editable = 'on';
                case 'Max Frequency'
                    str = 'o';
                    app.SettingEditField.Editable = 'on';
                case 'Current Location'
                    str = 'C';
                    app.SettingEditField.Editable = 'off';
                case 'Status Readout'
                    str = '$';
                    app.SettingEditField.Editable = 'off';
                case 'Command'
                    str = '$';
                    app.SettingEditField.Editable = 'on';
            end
            if strcmpi(Motor, 'X Motor')
                result=serialCom.writeToSerial(app.Xmotor,str);
            elseif strcmpi(Motor, 'Y Motor')
                result=serialCom.writeToSerial(app.Ymotor,str);
            elseif strcmpi(Motor, 'Z Motor')
                result=serialCom.writeToSerial(app.Zmotor,str);
            end
            if strcmpi(value,'Command')
                dispresult = result;
            else
                expression = '1(([:]accel)|([:]decel)|(u)|(o)|(C)|(\$))';
                replace = '';
                dispresult = regexprep(result,expression,replace);
            end
            app.SettingEditField.Value= dispresult;
        end

        % Value changed function: SettingEditField
        function SettingEditFieldValueChanged(app, event)
            value = app.SettingEditField.Value;
            value = strip(value,'left','0');
            setting = app.SettingDropDown.Value;
            Motor = app.MotorDropDown.Value;
            switch setting
                case 'Accel Ramp'
                    str = [':accel',value];
                case 'Decel Ramp'
                    str = [':decel',value];
                case 'Init Frequency'
                    str = ['u',value];
                case 'Max Frequency'
                    str = ['o',value];
                case 'Command'
                    str=value;
                otherwise
                    str = [];
            end
            if ~isempty(str)
                if strcmpi(Motor, 'X Motor')
                    result=serialCom.writeToSerial(app.Xmotor,str);
                elseif strcmpi(Motor, 'Y Motor')
                    result=serialCom.writeToSerial(app.Ymotor,str);
                elseif strcmpi(Motor, 'Z Motor')
                    result=serialCom.writeToSerial(app.Zmotor,str);
                end
                if strcmpi(setting,'Command')
                    
                    dispresult = result;
                else
                    expression = '1(([:]accel)|([:]decel)|(u)|(o)|(C)|(\$))';
                    replace = '';
                    dispresult = regexprep(result,expression,replace);
                end
                app.SettingEditField.Value= dispresult;
            end
        end

        % Button pushed function: RecenterXButton, RecenterYButton, 
        % RecenterZButton
        function RecenterMotorButtonPushed(app, event)
            switch event.Source.Text
                case 'Recenter X'
                    serialCom.returnHome(app.Xmotor);
                case 'Recenter Y'
                    serialCom.returnHome(app.Ymotor);
                case 'Recenter Z'
                    serialCom.returnHome(app.Zmotor);
                otherwise
            end
        end

        % Value changed function: WBBlueSpinner, WBRedSpinner
        function WBRedSpinnerValueChanged(app, event)
            redValue = app.WBRedSpinner.Value;
            blueValue = app.WBBlueSpinner.Value;
            app.VidSrc.WhiteBalanceRB = [redValue blueValue];
        end

        % Button pushed function: ResetWBButton
        function ResetWBButtonPushed(app, event)
            app.WBRedSpinner.Value = app.initWBRed;
            app.WBBlueSpinner.Value = app.initWBBlue;
            app.VidSrc.WhiteBalanceRB = [app.initWBRed app.initWBBlue];
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            imaqreset;
            app.FamilyDropDown.Enable = 'on';
            cameraInfo = imaqhwinfo;
            app.FamilyDropDown.Items = ['Select Family',cameraInfo.InstalledAdaptors,'Nikon'];
            app.FamilyDropDown.Value = 'Select Family';
            app.FormatDropDown.Enable = 'off';
            app.FormatDropDown.Items ={''};
            app.ResetButton.Enable = 'off';
            app.OpenPreviewWindowButton.Enable = 'off';
            app.WBRedSpinner.Enable = 'off';
            app.WBRedSpinner.Value = 0;
            app.WBBlueSpinner.Enable = 'off';
            app.WBBlueSpinner.Value = 0;
            app.ResetWBButton.Enable = 'off';
            app.VidObj = [];
            app.VidSrc = [];
            enableCarrythrough(app);
        end

        % Value changed function: GroupingKnob
        function GroupingKnobValueChanged(app, event)
            value = app.GroupingKnob.Value;
            if strcmpi(value,'Individual')
                app.IndividualLevelSlider.Enable = 'on';
            else
                app.IndividualLevelSlider.Enable = 'off';
            end
        end

        % Button pushed function: ResetAllComPortsButton
        function ResetAllComPortsButtonPushed(app, event)
            serialCom.endAllSerial;
            list = ['Not Active', seriallist];
            app.XMotorDropDown.Value = 'Not Active';
            app.XMotorDropDown.Items = list;
            app.YMotorDropDown.Value = 'Not Active';
            app.YMotorDropDown.Items = list;
            app.ZMotorDropDown.Value = 'Not Active';
            app.ZMotorDropDown.Items = list;
            app.Xmotor = [];
            app.Ymotor = [];
            app.Zmotor = [];
            enableCarrythrough(app);
        end

        % Value changed function: SampleSpinner
        function SampleSpinnerValueChanged(app, event)
            app.SampleCount = app.SampleSpinner.Value;
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            if ishandle(app.htop)
                close(app.htop);
                drawnow;
            end
            camera.autoFocusGaussian(app.VidObj,app.Zmotor,app.FocusStepEditField.Value);
        end

        % Button pushed function: DownButton_2, UpButton_2
        function FocusMovePushed(app, event)
            stepVal = app.FocusStepEditField.Value;
            mapNeg = [1,-1];
            switch event.Source
                case app.DownButton_2
                    dist = abs(stepVal)*mapNeg(app.InvertZCheckBox.Value+1);
                    app.ActiveCom = true;
                    serialCom.stepMove(app.Zmotor ,dist);
                    app.ActiveCom = false;
                case app.UpButton_2
                    dist = abs(stepVal)*mapNeg(app.InvertZCheckBox.Value+1);
                    app.ActiveCom = true;
                    serialCom.stepMove(app.Zmotor ,-dist);
                    app.ActiveCom = false;
            end
        end

        % Selection change function: TabGroup3
        function TabGroup3SelectionChanged(app, event)
            selectedTab = app.TabGroup3.SelectedTab;
            if strcmpi(selectedTab.Title,'Devices')
                MotorDropDownValueChanged(app);
            end
        end

        % Button pushed function: ZstackStartButton
        function StartZstackButtonPushed(app, event)
            if ishandle(app.htop)
                close(app.htop);
                drawnow;
            end
            mapNeg = [1,-1];
            zLoc = curZ(app);
            serialCom.stepMove(app.Zmotor,app.StepsImageSpinner.Value*(round(app.ImagesSpinner.Value/2))*mapNeg(app.InvertZCheckBox.Value+1));
            savename = app.FileNameEditField.Value;
            
            if (app.WhiteValueSpinner.Value ~= 0) && (strcmpi(app.WhiteLEDsSwitch.Value,'On'))
                lightStr = sprintf('W%d',app.WhiteValueSpinner.Value);
            else
                lightStr = '';
            end
            
            if (app.BlueValueSpinner.Value ~= 0) && (strcmpi(app.BlueLEDsSwitch.Value,'On'))
                activeBlueLights(app,lightStr);
            else
                % Turn Blue off
                if(strcmpi(app.BlueLEDsSwitch.Value,'Off'))
                    serialCom.writeToSerial(app.Ymotor,'LB000:000');
                end
            end
            
            if strcmpi(app.SaveImageButton.Enable,'on')
                app.GroupingKnob.Value = 'All LEDs';
                oldDir = app.SaveDirEditField.Value;
                i = 1;
                filledSaveName = SaveName(app,savename,lightStr);
                savedir = fullfile(oldDir,sprintf('Z-stack-%s',filledSaveName));
                while isfolder(savedir)
                    savedir = fullfile(oldDir,sprintf('Z-stack-%s_(%d)',filledSaveName,i));
                    i=i+1;
                end
                mkdir(savedir);
                app.SaveDir = savedir;
                imagenum = 1;
                savename2 = sprintf('Img%03g',imagenum);
                app.FileNameEditField.Value = savename2;
                imagenum = imagenum +1;
                writeImage(app,savename2);
                for i= 1:app.ImagesSpinner.Value-1
                    serialCom.stepMove(app.Zmotor,-app.StepsImageSpinner.Value*mapNeg(app.InvertZCheckBox.Value+1));
                    savename2 = sprintf('Img%03g',imagenum);
                    app.FileNameEditField.Value = savename2;
                    imagenum = imagenum +1;
                    writeImage(app,savename2);
                end
                app.SaveDir = oldDir;
                app.FileNameEditField.Value = savename;
                fileName = fullfile(oldDir,[filledSaveName,app.FormatDropDown_2.Value(2:end)]);
                if ~app.StackImagesAtEndCheckBox.Value
                    helicon = getpref('ImagingPlatformGauss','Helicon');
                    cmd= sprintf('"%s" -silent "%s" -save:"%s" -mp:1',helicon.Loc,savedir,fileName);
                    [~,~] = system(cmd);
                    rmdir(savedir, 's');
                end
            else
                uialert(app.UIFigure,'No Save settings set!','Save Settings');
                return
            end
            
            % deletStatus = rmdir(savedir, 's');
            
            % time = toc;
            % fprintf('%s focused in:%d seconds.\n',filledSaveName,time);
            app.UITable.Data = [app.UITable.Data;{fileName},curX(app),curY(app),NaN,NaN];
            zLoc2 = curZ(app);
            serialCom.stepMove(app.Zmotor, zLoc-zLoc2);
            % fprintf(1,'%d found in %5.2f seconds.\n',tempHyphaeCnt, time);
            
        end

        % Button pushed function: AutoLayoutButton
        function AutoLayoutButtonPushed(app, event)
            if ishandle(app.htop)
                close(app.htop);
                drawnow;
            end
            AutoButtonPushed(app);
            StartZstackButtonPushed(app);
        end

        % Button pushed function: StartSearchButton
        function StartSearchButtonPushed(app, event)
            if ishandle(app.htop)
                close(app.htop);
                drawnow;
            end
            serialCom.setExtentPoint(app.Zmotor);
            focusAtEnd = app.StackImagesAtEndCheckBox.Value;
            StartX = curX(app);
            StartY = curY(app);
            xLocPt = zeros(length(app.xCenters)*length(app.yCenters),1);
            yLocPt = zeros(length(app.xCenters)*length(app.yCenters),1);
            j = 0;
            for y=1:1%length(app.yCenters)
                if mod(y,2)==1
                    for x=1:1%length(app.xCenters)
                        j = j+1;
%                         if ~isempty(app.labels)||strcmpi(app.labels(x,y),'N/A')
                            app.CurLable = 'newLable';
%                         else
                            %                         if i> 228
                            serialCom.moveTo(app.Xmotor,StartX+app.xCenters(x));
                            serialCom.moveTo(app.Ymotor,StartY+app.yCenters(y));
                            xLocPt(j) = StartX+app.xCenters(x);
                            yLocPt(j) = StartY+app.yCenters(y);
                            plot(app.UIAxes,xLocPt,yLocPt,'.','MarkerIndices',1:j);
                            drawnow;
                            AutoLayoutButtonPushed(app);
                            %                         else
                            %                             i = i +1;
                            %                         end
%                         end
                    end
                else
                    for x=length(app.xCenters):-1:1
                        j = j+1;
                        if strcmpi(app.label(x,y),'N/A')
                            
                        else
                            %                         if i> 228
                            serialCom.moveTo(app.Xmotor,StartX+app.xCenters(x));
                            serialCom.moveTo(app.Ymotor,StartY+app.yCenters(y));
                            xLocPt(j) = StartX+app.xCenters(x);
                            yLocPt(j) = StartY+app.yCenters(y);
                            plot(app.UIAxes,xLocPt,yLocPt,'.','MarkerIndices',1:j);
                            drawnow;
                            AutoLayoutButtonPushed(app);
                            %                         else
                            %                             i = i +1;
                            %                         end
                        end
                    end
                end
            end
            if focusAtEnd
                saveDirTop = app.SaveDirEditField.Value;
                contents = dir(saveDirTop);
                contents = struct2table(contents);
                folders = contents(contents.isdir,:);
                folders = folders(~contains(folders.name,'.'),:);
                files = contents(~contents.isdir,:);
                focusedNames = regexprep(folders.name,'Z-stack-','');
                for i = 1:numel(focusedNames)
                    if ~any(strcmpi(focusedNames{i},files.name))
                        helicon = getpref('ImagingPlatformGauss','Helicon');
                        fileName = fullfile(saveDirTop,[focusedNames{i},app.FormatDropDown_2.Value(2:end)]);
                        cmd= sprintf('"%s" -silent "%s" -save:"%s" -mp:1',helicon.Loc,fullfile(saveDirTop,folders.name{i}),fileName);
                        [~,~] = system(cmd);
                        rmdir(fullfile(saveDirTop,folders.name{i}), 's');
                    end
                end
            end
            play(app.Audio);
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            t = cell2table(app.UITable.Data,'VariableNames',app.UITable.ColumnName);
            writetable(t,fullfile(app.SaveDir,'data.xlsx'));
        end

        % Menu selected function: ResetPrefrencesMenu
        function ResetPrefrencesMenuSelected(app, event)
            setpref('ImagingPlatformGauss','saveSetup','ask');
        end

        % Button pushed function: autoFocusSearchButton
        function autoFocusSearchButtonPushed(app, event)
            camera.testFocusGaussian(app.VidObj,app.Zmotor,app.FocusStepEditField.Value);
        end

        % Button pushed function: DisplayImageButton
        function DisplayImageButtonPushed(app, event)
            img = CurImg(app);
            imtool(img);
            disp(app.curX)
            disp(app.curY)
        end

        % Button pushed function: BrowseButton_3
        function BrowseButton_3Pushed(app, event)
            [fileName,pathName] = uigetfile('*.xls;*.xlsx');
            if ~isnumeric(fileName)
                file = fullfile(pathName,fileName);
                app.LableFileEditField.Value = file;
                app.ReadLabelsButton.Enable = 'on';
            end
        end

        % Button pushed function: ReadLabelsButton
        function ReadLabelsButtonPushed(app, event)
            if exist(app.LableFileEditField.Value,'file') == 2
                file = fullfile(app.LableFileEditField.Value);
                app.labels = importLabels(file);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PrefrencesMenu = uimenu(app.UIFigure);
            app.PrefrencesMenu.Text = 'Prefrences';
            app.ResetPrefrencesMenu = uimenu(app.PrefrencesMenu);
            app.ResetPrefrencesMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetPrefrencesMenuSelected, true);
            app.ResetPrefrencesMenu.Text = 'Reset Prefrences';
            app.TabGroup3 = uitabgroup(app.UIFigure);
            app.TabGroup3.SelectionChangedFcn = createCallbackFcn(app, @TabGroup3SelectionChanged, true);
            app.DevicesTab = uitab(app.TabGroup3);
            app.DevicesTab.Title = 'Devices';
            app.MotorControllerPanel = uipanel(app.DevicesTab);
            app.MotorControllerPanel.Title = 'Motor Controller';
            app.InvertXCheckBox = uicheckbox(app.MotorControllerPanel);
            app.InvertXCheckBox.Enable = 'off';
            app.InvertXCheckBox.Text = 'Invert';
            app.InvertYCheckBox = uicheckbox(app.MotorControllerPanel);
            app.InvertYCheckBox.Enable = 'off';
            app.InvertYCheckBox.Text = 'Invert';
            app.InvertZCheckBox = uicheckbox(app.MotorControllerPanel);
            app.InvertZCheckBox.Enable = 'off';
            app.InvertZCheckBox.Text = 'Invert';
            app.XMotorLabel = uilabel(app.MotorControllerPanel);
            app.XMotorLabel.Text = 'X Motor';
            app.XMotorDropDown = uidropdown(app.MotorControllerPanel);
            app.XMotorDropDown.Items = {'Not Active'};
            app.XMotorDropDown.ValueChangedFcn = createCallbackFcn(app, @MotorSourceValueChanged, true);
            app.XMotorDropDown.Value = 'Not Active';
            app.TypeDropDownLabel = uilabel(app.MotorControllerPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.XTypeDropDown = uidropdown(app.MotorControllerPanel);
            app.XTypeDropDown.Items = {'Select Type', 'Nanotech', 'MSP432'};
            app.XTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.XTypeDropDown.Enable = 'off';
            app.XTypeDropDown.Value = 'Select Type';
            app.RecenterXButton = uibutton(app.MotorControllerPanel, 'push');
            app.RecenterXButton.ButtonPushedFcn = createCallbackFcn(app, @RecenterMotorButtonPushed, true);
            app.RecenterXButton.Enable = 'off';
            app.RecenterXButton.Text = 'Recenter X';
            app.RecenterYButton = uibutton(app.MotorControllerPanel, 'push');
            app.RecenterYButton.ButtonPushedFcn = createCallbackFcn(app, @RecenterMotorButtonPushed, true);
            app.RecenterYButton.Enable = 'off';
            app.RecenterYButton.Text = 'Recenter Y';
            app.RecenterZButton = uibutton(app.MotorControllerPanel, 'push');
            app.RecenterZButton.ButtonPushedFcn = createCallbackFcn(app, @RecenterMotorButtonPushed, true);
            app.RecenterZButton.Enable = 'off';
            app.RecenterZButton.Text = 'Recenter Z';
            app.TypeDropDown_2Label = uilabel(app.MotorControllerPanel);
            app.TypeDropDown_2Label.Text = 'Type';
            app.YTypeDropDown = uidropdown(app.MotorControllerPanel);
            app.YTypeDropDown.Items = {'Select Type', 'Nanotech', 'MSP432'};
            app.YTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.YTypeDropDown.Enable = 'off';
            app.YTypeDropDown.Value = 'Select Type';
            app.YMotorDropDownLabel = uilabel(app.MotorControllerPanel);
            app.YMotorDropDownLabel.Text = 'Y Motor';
            app.YMotorDropDown = uidropdown(app.MotorControllerPanel);
            app.YMotorDropDown.Items = {'Not Active'};
            app.YMotorDropDown.ValueChangedFcn = createCallbackFcn(app, @MotorSourceValueChanged, true);
            app.YMotorDropDown.Value = 'Not Active';
            app.ZMotorDropDownLabel = uilabel(app.MotorControllerPanel);
            app.ZMotorDropDownLabel.Text = 'Z Motor';
            app.ZMotorDropDown = uidropdown(app.MotorControllerPanel);
            app.ZMotorDropDown.Items = {'Not Active'};
            app.ZMotorDropDown.ValueChangedFcn = createCallbackFcn(app, @MotorSourceValueChanged, true);
            app.ZMotorDropDown.Value = 'Not Active';
            app.TypeDropDownLabel_2 = uilabel(app.MotorControllerPanel);
            app.TypeDropDownLabel_2.Text = 'Type';
            app.ZTypeDropDown = uidropdown(app.MotorControllerPanel);
            app.ZTypeDropDown.Items = {'Select Type', 'Nanotech', 'MSP432'};
            app.ZTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.ZTypeDropDown.Enable = 'off';
            app.ZTypeDropDown.Value = 'Select Type';
            app.MotorDropDownLabel = uilabel(app.MotorControllerPanel);
            app.MotorDropDownLabel.Text = 'Motor';
            app.MotorDropDown = uidropdown(app.MotorControllerPanel);
            app.MotorDropDown.Items = {'Select Motor'};
            app.MotorDropDown.ValueChangedFcn = createCallbackFcn(app, @MotorDropDownValueChanged, true);
            app.MotorDropDown.Enable = 'off';
            app.MotorDropDown.Value = 'Select Motor';
            app.SettingDropDownLabel = uilabel(app.MotorControllerPanel);
            app.SettingDropDownLabel.Text = 'Setting';
            app.SettingDropDown = uidropdown(app.MotorControllerPanel);
            app.SettingDropDown.Items = {'Current Location', 'Accel Ramp', 'Decel Ramp', 'Init Frequency', 'Max Frequency', 'Status Readout', 'Command'};
            app.SettingDropDown.ValueChangedFcn = createCallbackFcn(app, @SettingDropDownValueChanged, true);
            app.SettingDropDown.Enable = 'off';
            app.SettingDropDown.Value = 'Current Location';
            app.SettingEditField = uieditfield(app.MotorControllerPanel, 'text');
            app.SettingEditField.ValueChangedFcn = createCallbackFcn(app, @SettingEditFieldValueChanged, true);
            app.SettingEditField.Editable = 'off';
            app.ResetAllComPortsButton = uibutton(app.MotorControllerPanel, 'push');
            app.ResetAllComPortsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllComPortsButtonPushed, true);
            app.ResetAllComPortsButton.Text = 'Reset All Com Ports';
            app.CameraPanel = uipanel(app.DevicesTab);
            app.CameraPanel.Title = 'Camera';
            app.FamilyDropDownLabel = uilabel(app.CameraPanel);
            app.FamilyDropDownLabel.Text = 'Family';
            app.FamilyDropDown = uidropdown(app.CameraPanel);
            app.FamilyDropDown.Items = {};
            app.FamilyDropDown.ValueChangedFcn = createCallbackFcn(app, @FamilyDropDownValueChanged, true);
            app.FamilyDropDown.Value = {};
            app.FormatDropDownLabel = uilabel(app.CameraPanel);
            app.FormatDropDownLabel.Enable = 'off';
            app.FormatDropDownLabel.Text = 'Format';
            app.FormatDropDown = uidropdown(app.CameraPanel);
            app.FormatDropDown.Items = {};
            app.FormatDropDown.ValueChangedFcn = createCallbackFcn(app, @FormatDropDownValueChanged, true);
            app.FormatDropDown.Enable = 'off';
            app.FormatDropDown.Value = {};
            app.ResetButton = uibutton(app.CameraPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.OpenPreviewWindowButton = uibutton(app.CameraPanel, 'push');
            app.OpenPreviewWindowButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPreviewWindowButtonPushed, true);
            app.OpenPreviewWindowButton.Enable = 'off';
            app.OpenPreviewWindowButton.Text = 'Open Preview Window';
            app.WBRedSpinnerLabel = uilabel(app.CameraPanel);
            app.WBRedSpinnerLabel.Text = 'WB Red';
            app.WBRedSpinner = uispinner(app.CameraPanel);
            app.WBRedSpinner.Limits = [0 1023];
            app.WBRedSpinner.ValueChangedFcn = createCallbackFcn(app, @WBRedSpinnerValueChanged, true);
            app.WBRedSpinner.Enable = 'off';
            app.WBBlueSpinnerLabel = uilabel(app.CameraPanel);
            app.WBBlueSpinnerLabel.Text = 'WB Blue';
            app.WBBlueSpinner = uispinner(app.CameraPanel);
            app.WBBlueSpinner.Limits = [0 1023];
            app.WBBlueSpinner.ValueChangedFcn = createCallbackFcn(app, @WBRedSpinnerValueChanged, true);
            app.WBBlueSpinner.Enable = 'off';
            app.ResetWBButton = uibutton(app.CameraPanel, 'push');
            app.ResetWBButton.ButtonPushedFcn = createCallbackFcn(app, @ResetWBButtonPushed, true);
            app.ResetWBButton.Enable = 'off';
            app.ResetWBButton.Text = 'Reset WB';
            app.ControlTab = uitab(app.TabGroup3);
            app.ControlTab.Title = 'Control';
            app.XYStagePanel = uipanel(app.ControlTab);
            app.XYStagePanel.Title = 'XY Stage';
            app.UpButton = uibutton(app.XYStagePanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @XYMoveButtonPushed, true);
            app.UpButton.Enable = 'off';
            app.UpButton.Text = 'Up';
            app.RightButton = uibutton(app.XYStagePanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @XYMoveButtonPushed, true);
            app.RightButton.Enable = 'off';
            app.RightButton.Text = 'Right';
            app.LeftButton = uibutton(app.XYStagePanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @XYMoveButtonPushed, true);
            app.LeftButton.Enable = 'off';
            app.LeftButton.Text = 'Left';
            app.DownButton = uibutton(app.XYStagePanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @XYMoveButtonPushed, true);
            app.DownButton.Enable = 'off';
            app.DownButton.Text = 'Down';
            app.DistancemmEditFieldLabel = uilabel(app.XYStagePanel);
            app.DistancemmEditFieldLabel.Text = 'Distance (mm)';
            app.DistancemmEditField = uieditfield(app.XYStagePanel, 'numeric');
            app.DistancemmEditField.Limits = [0 Inf];
            app.DistancemmEditField.Value = 1;
            app.SaveData = uitabgroup(app.ControlTab);
            app.ImageTab = uitab(app.SaveData);
            app.ImageTab.Title = 'Image';
            app.SaveImageButton = uibutton(app.ImageTab, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Enable = 'off';
            app.SaveImageButton.Text = 'Save Image';
            app.SaveDirEditFieldLabel = uilabel(app.ImageTab);
            app.SaveDirEditFieldLabel.Text = 'Save Dir';
            app.SaveDirEditField = uieditfield(app.ImageTab, 'text');
            app.SaveDirEditField.Editable = 'off';
            app.BrowseButton = uibutton(app.ImageTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FileNameEditFieldLabel = uilabel(app.ImageTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.ImageTab, 'text');
            app.FileNameEditField.Value = '[DATE]_[TIME]_[LIGHT]_[NUM]';
            app.FormatDropDown_2Label = uilabel(app.ImageTab);
            app.FormatDropDown_2Label.Text = 'Format';
            app.FormatDropDown_2 = uidropdown(app.ImageTab);
            app.FormatDropDown_2.Items = {'*.bmp', '*.gif', '*.jpeg', '*.png', '*.ras', '*.tiff'};
            app.FormatDropDown_2.Value = '*.tiff';
            app.SampleSpinnerLabel = uilabel(app.ImageTab);
            app.SampleSpinnerLabel.Text = 'Sample';
            app.SampleSpinner = uispinner(app.ImageTab);
            app.SampleSpinner.Limits = [1 Inf];
            app.SampleSpinner.RoundFractionalValues = 'on';
            app.SampleSpinner.ValueChangedFcn = createCallbackFcn(app, @SampleSpinnerValueChanged, true);
            app.SampleSpinner.Value = 1;
            app.ZstackStartButton = uibutton(app.ImageTab, 'push');
            app.ZstackStartButton.ButtonPushedFcn = createCallbackFcn(app, @StartZstackButtonPushed, true);
            app.ZstackStartButton.Enable = 'off';
            app.ZstackStartButton.Text = 'Z-stack Start';
            app.AutoLayoutButton = uibutton(app.ImageTab, 'push');
            app.AutoLayoutButton.ButtonPushedFcn = createCallbackFcn(app, @AutoLayoutButtonPushed, true);
            app.AutoLayoutButton.Enable = 'off';
            app.AutoLayoutButton.Text = 'Auto Layout';
            app.StepsImageSpinnerLabel = uilabel(app.ImageTab);
            app.StepsImageSpinnerLabel.Text = 'Steps / Image';
            app.StepsImageSpinner = uispinner(app.ImageTab);
            app.StepsImageSpinner.Limits = [3 300];
            app.StepsImageSpinner.Value = 3;
            app.ImagesSpinnerLabel = uilabel(app.ImageTab);
            app.ImagesSpinnerLabel.Text = 'Images';
            app.ImagesSpinner = uispinner(app.ImageTab);
            app.ImagesSpinner.Limits = [1 Inf];
            app.ImagesSpinner.ValueDisplayFormat = '%.0f';
            app.ImagesSpinner.Value = 10;
            app.LightingTab = uitab(app.SaveData);
            app.LightingTab.Title = 'Lighting';
            app.WhiteLEDsSwitchLabel = uilabel(app.LightingTab);
            app.WhiteLEDsSwitchLabel.Text = 'White LEDs';
            app.WhiteLEDsSwitch = uiswitch(app.LightingTab, 'slider');
            app.WhiteLEDsSwitch.Value = 'On';
            app.GroupingKnobLabel = uilabel(app.LightingTab);
            app.GroupingKnobLabel.Text = 'Grouping';
            app.GroupingKnob = uiknob(app.LightingTab, 'discrete');
            app.GroupingKnob.Items = {'All LEDs', 'Color Group', 'Individual'};
            app.GroupingKnob.ValueChangedFcn = createCallbackFcn(app, @GroupingKnobValueChanged, true);
            app.GroupingKnob.Value = 'All LEDs';
            app.BlueLEDsSwitchLabel = uilabel(app.LightingTab);
            app.BlueLEDsSwitchLabel.Text = 'Blue LEDs';
            app.BlueLEDsSwitch = uiswitch(app.LightingTab, 'slider');
            app.BlueLEDsSwitch.Value = 'On';
            app.IndividualLevelLabel = uilabel(app.LightingTab);
            app.IndividualLevelLabel.Enable = 'off';
            app.IndividualLevelLabel.Text = 'Individual Level';
            app.IndividualLevelSlider = uislider(app.LightingTab);
            app.VideoTab = uitab(app.SaveData);
            app.VideoTab.Title = 'Video';
            app.SaveVideoButton = uibutton(app.VideoTab, 'push');
            app.SaveVideoButton.Enable = 'off';
            app.SaveVideoButton.Text = 'Save Video';
            app.BrowseButton_2 = uibutton(app.VideoTab, 'push');
            app.BrowseButton_2.Enable = 'off';
            app.BrowseButton_2.Text = 'Browse';
            app.SaveDirEditField_2Label = uilabel(app.VideoTab);
            app.SaveDirEditField_2Label.Text = 'Save Dir';
            app.SaveDirEditField_2 = uieditfield(app.VideoTab, 'text');
            app.SaveDirEditField_2.Editable = 'off';
            app.SaveDirEditField_2.Enable = 'off';
            app.FileNameEditField_2Label = uilabel(app.VideoTab);
            app.FileNameEditField_2Label.Text = 'File Name';
            app.FileNameEditField_2 = uieditfield(app.VideoTab, 'text');
            app.FileNameEditField_2.Enable = 'off';
            app.FileNameEditField_2.Value = 'Video1';
            app.FileFormatDropDownLabel = uilabel(app.VideoTab);
            app.FileFormatDropDownLabel.Text = 'FileFormat';
            app.FileFormatDropDown = uidropdown(app.VideoTab);
            app.FileFormatDropDown.Items = {'*.bmp', '*.gif', '*.jpeg', '*.png', '*.ras', '*.tiff'};
            app.FileFormatDropDown.Enable = 'off';
            app.FileFormatDropDown.Value = '*.tiff';
            app.NamingTab = uitab(app.SaveData);
            app.NamingTab.Title = 'Naming';
            app.TextArea_3 = uitextarea(app.NamingTab);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'''[DATE]'' = current Date in mmddyy '; '''[TIME]'' = current Time in HHMMSS'; '''[NUM]'', ''[NUMBER]'', or ''[#]''  = Sample Number'; '''[LIGHT]'' = LEDs Setting'; '''[LOC]'' = Current Location in (X, Y, Z) format'};
            app.LEDsGroup = uitabgroup(app.ControlTab);
            app.BlueLEDsTab = uitab(app.LEDsGroup);
            app.BlueLEDsTab.Title = 'Blue LEDs';
            app.IntensityKnobLabel = uilabel(app.BlueLEDsTab);
            app.IntensityKnobLabel.Text = 'Intensity';
            app.BlueIntensityKnob = uiknob(app.BlueLEDsTab, 'continuous');
            app.BlueIntensityKnob.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.BlueIntensityKnob.ValueChangedFcn = createCallbackFcn(app, @IntensityKnobValueChanged, true);
            app.BlueIntensityKnob.ValueChangingFcn = createCallbackFcn(app, @IntensityKnobValueChanged, true);
            app.TopBlueCheckBox = uicheckbox(app.BlueLEDsTab);
            app.TopBlueCheckBox.Text = 'Top Blue';
            app.LeftBlueCheckBox = uicheckbox(app.BlueLEDsTab);
            app.LeftBlueCheckBox.Text = 'Left Blue';
            app.BottomBlueCheckBox = uicheckbox(app.BlueLEDsTab);
            app.BottomBlueCheckBox.Text = 'Bottom Blue';
            app.RightBlueCheckBox = uicheckbox(app.BlueLEDsTab);
            app.RightBlueCheckBox.Text = 'Right Blue';
            app.TopLeftBlueCheckBox = uicheckbox(app.BlueLEDsTab);
            app.TopLeftBlueCheckBox.Text = 'Top/Left Blue';
            app.TopRightBlueCheckBox = uicheckbox(app.BlueLEDsTab);
            app.TopRightBlueCheckBox.Text = 'Top/Right Blue';
            app.BotLeftBlueCheckBox = uicheckbox(app.BlueLEDsTab);
            app.BotLeftBlueCheckBox.Text = 'Bot/Left Blue';
            app.BotRightBlueCheckBox = uicheckbox(app.BlueLEDsTab);
            app.BotRightBlueCheckBox.Text = 'Bot/Right Blue';
            app.ValueSpinnerLabel = uilabel(app.BlueLEDsTab);
            app.ValueSpinnerLabel.Text = 'Value';
            app.BlueValueSpinner = uispinner(app.BlueLEDsTab);
            app.BlueValueSpinner.ValueChangingFcn = createCallbackFcn(app, @IntensitySpinnerValueChanged, true);
            app.BlueValueSpinner.Limits = [0 100];
            app.BlueValueSpinner.RoundFractionalValues = 'on';
            app.BlueValueSpinner.ValueChangedFcn = createCallbackFcn(app, @IntensitySpinnerValueChanged, true);
            app.SetBlueLEDsButton = uibutton(app.BlueLEDsTab, 'push');
            app.SetBlueLEDsButton.ButtonPushedFcn = createCallbackFcn(app, @SetBlueLEDsButtonPushed, true);
            app.SetBlueLEDsButton.Text = 'Set Blue LEDs';
            app.WhiteLEDsTab = uitab(app.LEDsGroup);
            app.WhiteLEDsTab.Title = 'White LEDs';
            app.SetWhiteLEDsButton = uibutton(app.WhiteLEDsTab, 'push');
            app.SetWhiteLEDsButton.ButtonPushedFcn = createCallbackFcn(app, @SetWhiteLEDsButtonPushed, true);
            app.SetWhiteLEDsButton.Text = 'Set White LEDs';
            app.IntensityKnobLabel_2 = uilabel(app.WhiteLEDsTab);
            app.IntensityKnobLabel_2.Text = 'Intensity';
            app.WhiteIntensityKnob = uiknob(app.WhiteLEDsTab, 'continuous');
            app.WhiteIntensityKnob.ValueChangedFcn = createCallbackFcn(app, @IntensityKnobValueChanged, true);
            app.WhiteIntensityKnob.ValueChangingFcn = createCallbackFcn(app, @IntensityKnobValueChanged, true);
            app.ValueSpinnerLabel_2 = uilabel(app.WhiteLEDsTab);
            app.ValueSpinnerLabel_2.Text = 'Value';
            app.WhiteValueSpinner = uispinner(app.WhiteLEDsTab);
            app.WhiteValueSpinner.ValueChangingFcn = createCallbackFcn(app, @IntensitySpinnerValueChanged, true);
            app.WhiteValueSpinner.Limits = [0 100];
            app.WhiteValueSpinner.RoundFractionalValues = 'on';
            app.WhiteValueSpinner.ValueChangedFcn = createCallbackFcn(app, @IntensitySpinnerValueChanged, true);
            app.WhiteValueSpinner.Value = 100;
            app.FocusPanel = uipanel(app.ControlTab);
            app.FocusPanel.Title = 'Focus';
            app.UpButton_2 = uibutton(app.FocusPanel, 'push');
            app.UpButton_2.ButtonPushedFcn = createCallbackFcn(app, @FocusMovePushed, true);
            app.UpButton_2.Enable = 'off';
            app.UpButton_2.Text = 'Up';
            app.DownButton_2 = uibutton(app.FocusPanel, 'push');
            app.DownButton_2.ButtonPushedFcn = createCallbackFcn(app, @FocusMovePushed, true);
            app.DownButton_2.Enable = 'off';
            app.DownButton_2.Text = 'Down';
            app.AutoButton = uibutton(app.FocusPanel, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Enable = 'off';
            app.AutoButton.Text = 'Auto';
            app.FocusStepEditFieldLabel = uilabel(app.FocusPanel);
            app.FocusStepEditFieldLabel.Text = 'Focus Step';
            app.FocusStepEditField = uieditfield(app.FocusPanel, 'numeric');
            app.FocusStepEditField.Limits = [1 Inf];
            app.FocusStepEditField.Value = 3;
            app.SampleInfoTab = uitab(app.TabGroup3);
            app.SampleInfoTab.Title = 'SampleInfo';
            app.UITable = uitable(app.SampleInfoTab);
            app.UITable.ColumnName = {'FileName'; 'xLoc'; 'yLoc'; 'SampleLable'};
            app.UITable.RowName = {};
            app.ExportButton = uibutton(app.SampleInfoTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.SearchTab = uitab(app.TabGroup3);
            app.SearchTab.Title = 'Search';
            app.UIAxes = uiaxes(app.SearchTab);
            title(app.UIAxes, 'Sample Locations')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StartSearchButton = uibutton(app.SearchTab, 'push');
            app.StartSearchButton.ButtonPushedFcn = createCallbackFcn(app, @StartSearchButtonPushed, true);
            app.StartSearchButton.Text = 'Start Search';
            app.autoFocusSearchButton = uibutton(app.SearchTab, 'push');
            app.autoFocusSearchButton.ButtonPushedFcn = createCallbackFcn(app, @autoFocusSearchButtonPushed, true);
            app.autoFocusSearchButton.Text = 'autoFocusSearch';
            app.DisplayImageButton = uibutton(app.SearchTab, 'push');
            app.DisplayImageButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayImageButtonPushed, true);
            app.DisplayImageButton.Text = 'Display Image';
            app.LableFileEditFieldLabel = uilabel(app.SearchTab);
            app.LableFileEditFieldLabel.Text = 'LableFile';
            app.LableFileEditField = uieditfield(app.SearchTab, 'text');
            app.LableFileEditField.Value = 'Select a File';
            app.BrowseButton_3 = uibutton(app.SearchTab, 'push');
            app.BrowseButton_3.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_3Pushed, true);
            app.BrowseButton_3.Text = 'Browse';
            app.ReadLabelsButton = uibutton(app.SearchTab, 'push');
            app.ReadLabelsButton.ButtonPushedFcn = createCallbackFcn(app, @ReadLabelsButtonPushed, true);
            app.ReadLabelsButton.Enable = 'off';
            app.ReadLabelsButton.Text = 'Read Labels';
            app.StackImagesAtEndCheckBox = uicheckbox(app.SearchTab);
            app.StackImagesAtEndCheckBox.Text = 'Stack Images At End';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImagingPlatformGauss

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end