classdef PlantPlatform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        TabGroup               matlab.ui.container.TabGroup       % Nanotec...
        nanotec                matlab.ui.container.Tab            % Nanotec
        msp432                 matlab.ui.container.Tab            % MSP432
        Panel                  matlab.ui.container.Panel          % Current...
        LabelLamp              matlab.ui.control.Label            % Ready
        NanoReadyLamp          matlab.ui.control.Lamp            
        LabelDropDown          matlab.ui.control.Label            % Positio...
        NanoPositionMode       matlab.ui.control.DropDown         % Relativ...
        LabelNumericEditField  matlab.ui.control.Label            % Travel ...
        NanoTravelDistance     matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField2 matlab.ui.control.Label            % Acceler...
        NanoAccelRamp          matlab.ui.control.NumericEditField % [0 Inf]
        Label                  matlab.ui.control.Label            % Deceler...
        NanoDecelRamp          matlab.ui.control.NumericEditField % [0 Inf]
        Label2                 matlab.ui.control.Label            % Direction
        NanoDirection          matlab.ui.control.DropDown         % Forward...
        NanoPosErrorButton     matlab.ui.control.Button           % Positio...
        RunCommand             matlab.ui.control.Button           % Run Com...
        LabelLamp2             matlab.ui.control.Label            % Positio...
        NanoPosLamp            matlab.ui.control.Lamp            
        ReturnHome             matlab.ui.control.Button           % Return ...
        Preview                matlab.ui.container.Tab            % Preview
        Refresh                matlab.ui.control.Button           % Refresh...
    end

    
    properties (Access = public)
        sPortNano  % Description
        forward
        backward
    end


    methods (Access = private)
        
        function getStatus(app)
            output = serialCom.writeToSerial(app.sPortNano,'$');
            serialCom.comErrorCheck(output);
            statusBin = dec2binvec(str2double(output(5:end-1)));
            if statusBin(1) == 1
                app.NanoReadyLamp.Color = [0,1,0];
            else
                app.NanoReadyLamp.Color = [1,0,0];
            end
            if statusBin(3) == 0
                app.NanoPosLamp.Color = [0,1,0];
                app.NanoPosErrorButton.Enable = 'off';
            else
                app.NanoPosLamp.Color = [1,0,0];
                app.NanoPosErrorButton.Enable = 'on';
            end
            status = serialCom.statusReader(app.sPortNano);
            app.NanoPositionMode.Value= app.NanoPositionMode.ItemsData{status.positionMode};
            app.NanoTravelDistance.Value = status.travel;
            app.NanoAccelRamp.Value = status.accelRamp;
            app.NanoDecelRamp.Value = status.decelRamp;
            if status.direction == 0
                app.NanoDirection.Value = app.NanoDirection.ItemsData{1};
            else
                app.NanoDirection.Value = app.NanoDirection.ItemsData{2};
            end
        end
        
        function loadInputs(app)
            status = serialCom.statusReader(app.sPortNano);
            if ~strcmp(app.NanoPositionMode.Value,app.NanoPositionMode.ItemsData{status.positionMode})
                index = find(strcmp(app.NanoPositionMode.ItemsData,app.NanoPositionMode.Value));
                output = serialCom.writeToSerial(app.sPortNano,['p',index']);
                serialCom.comErrorCheck(output);
            end
            if app.NanoTravelDistance.Value ~= status.travel
                output = serialCom.writeToSerial(app.sPortNano,['s',num2str(app.NanoTravelDistance.Value)]);
                serialCom.comErrorCheck(output);
            end
            if app.NanoAccelRamp.Value ~= status.accelRamp
                output = serialCom.writeToSerial(app.sPortNano,[':accel',num2str(app.NanoAccelRamp.Value*1000)]);
                serialCom.comErrorCheck(output);
            end
            if app.NanoAccelRamp.Value == app.NanoDecelRamp.Value
                output = serialCom.writeToSerial(app.sPortNano,':decel0');
                serialCom.comErrorCheck(output);
            elseif app.NanoDecelRamp.Value ~= status.decelRamp
                output = serialCom.writeToSerial(app.sPortNano,[':decel',num2str(app.NanoDecelRamp.Value*1000)]);
                serialCom.comErrorCheck(output);
            end
            index = find(strcmp(app.NanoDirection.ItemsData,app.NanoDirection.Value))-1;
            if index~=status.direction
                output = serialCom.writeToSerial(app.sPortNano,['d',num2str(index)]);
                serialCom.comErrorCheck(output);
            end
            
        end
        
        function waitTillReady(app)
            app.NanoPositionMode.Enable = 'off';
            app.NanoTravelDistance.Enable = 'off';
            app.NanoAccelRamp.Enable = 'off';
            app.NanoDecelRamp.Enable = 'off';
            app.NanoDirection.Enable = 'off';
            serialCom.waitTillReady(app.sPortNano)
            app.NanoPositionMode.Enable = 'on';
            app.NanoTravelDistance.Enable = 'on';
            app.NanoAccelRamp.Enable = 'on';
            app.NanoDecelRamp.Enable = 'on';
            app.NanoDirection.Enable = 'on';
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.sPortNano = serialCom.startSerial;
            getStatus(app);
        end

        % UIFigure close request function
        function UIFigureCloseRequest(app)
            serialCom.endAllSerial;
            delete(app.UIFigure);
        end

        % NanoPosErrorButton button pushed function
        function NanoPosErrorButtonPushed(app)
            serialCom.writeToSerial(app.sPortNano,'D');
            getStatus(app);
        end

        % NanoLoadCommand button pushed function
        function NanoLoadCommandButtonPushed(app)
            
        end

        % RunCommand button pushed function
        function RunCommandButtonPushed(app)
            loadInputs(app);
            output = serialCom.writeToSerial(app.sPortNano,'A');
            serialCom.comErrorCheck(output);
            waitTillReady(app)
            getStatus(app);
        end

        % ReturnHome button pushed function
        function ReturnHomeButtonPushed(app)
            app.NanoPositionMode.Enable = 'off';
            app.NanoTravelDistance.Enable = 'off';
            app.NanoAccelRamp.Enable = 'off';
            app.NanoDecelRamp.Enable = 'off';
            app.NanoDirection.Enable = 'off';
            serialCom.returnHome(app.sPortNano);
            app.NanoPositionMode.Enable = 'on';
            app.NanoTravelDistance.Enable = 'on';
            app.NanoAccelRamp.Enable = 'on';
            app.NanoDecelRamp.Enable = 'on';
            app.NanoDirection.Enable = 'on';
        end

        % Refresh button pushed function
        function RefreshButtonPushed(app)
            getStatus(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest);
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.nanotec = uitab(app.TabGroup);
            app.nanotec.Units = 'pixels';
            app.nanotec.Title = 'Nanotec';
            app.Panel = uipanel(app.nanotec);
            app.Panel.Title = 'Current Status';
            app.Panel.Units = 'pixels';
            app.LabelLamp = uilabel(app.Panel);
            app.LabelLamp.Text = 'Ready';
            app.NanoReadyLamp = uilamp(app.Panel);
            app.LabelDropDown = uilabel(app.Panel);
            app.LabelDropDown.Text = 'Position Mode';
            app.NanoPositionMode = uidropdown(app.Panel);
            app.NanoPositionMode.Items = {'Relative', 'Absolute', 'Internal Reference Run', 'External Reference Run', 'Speed', 'Flag Positioning', 'Clock direction mode - Left', 'Clock direction mode - Right', 'Clock direction mode-Internal reference run', 'Clock direction mode-External reference run', 'Analog', 'Joystick', 'Analog positioning', 'HW reference', 'Torque mode', 'CL quick test mode', 'CL test mode', 'CL Autotune mode', 'CL quick test mode 2'};
            app.NanoPositionMode.ItemsData = {'Relative', 'Absolute', 'Internal Reference Run', 'External Reference Run', 'Speed', 'Flag Positioning', 'Clock direction mode - Left', 'Clock direction mode - Right', 'Clock direction mode-Internal reference run', 'Clock direction mode-External reference run', 'Analog', 'Joystick', 'Analog positioning', 'HW reference', 'Torque mode', 'CL quick test mode', 'CL test mode', 'CL Autotune mode', 'CL quick test mode 2'};
            app.NanoPositionMode.Value = 'Relative';
            app.LabelNumericEditField = uilabel(app.Panel);
            app.LabelNumericEditField.Text = 'Travel Distance';
            app.NanoTravelDistance = uieditfield(app.Panel, 'numeric');
            app.NanoTravelDistance.ValueDisplayFormat = '%.0f';
            app.LabelNumericEditField2 = uilabel(app.Panel);
            app.LabelNumericEditField2.Text = 'Acceleration Ramp';
            app.NanoAccelRamp = uieditfield(app.Panel, 'numeric');
            app.NanoAccelRamp.Limits = [0 Inf];
            app.NanoAccelRamp.ValueDisplayFormat = '%.0f';
            app.NanoAccelRamp.Editable = 'off';
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'Deceleration Ramp';
            app.NanoDecelRamp = uieditfield(app.Panel, 'numeric');
            app.NanoDecelRamp.Limits = [0 Inf];
            app.NanoDecelRamp.ValueDisplayFormat = '%.0f';
            app.NanoDecelRamp.Editable = 'off';
            app.Label2 = uilabel(app.Panel);
            app.Label2.Text = 'Direction';
            app.NanoDirection = uidropdown(app.Panel);
            app.NanoDirection.Items = {'Forward', 'Backward'};
            app.NanoDirection.ItemsData = {'Forward', 'Backward', ''};
            app.NanoDirection.Value = 'Forward';
            app.LabelLamp2 = uilabel(app.Panel);
            app.LabelLamp2.Text = 'Position Error';
            app.NanoPosLamp = uilamp(app.Panel);
            app.NanoPosErrorButton = uibutton(app.Panel, 'push');
            app.NanoPosErrorButton.ButtonPushedFcn = createCallbackFcn(app, @NanoPosErrorButtonPushed);
            app.NanoPosErrorButton.Text = 'Position Error Reset';
            app.RunCommand = uibutton(app.nanotec, 'push');
            app.RunCommand.ButtonPushedFcn = createCallbackFcn(app, @RunCommandButtonPushed);
            app.RunCommand.Text = 'Run Command';
            app.ReturnHome = uibutton(app.nanotec, 'push');
            app.ReturnHome.ButtonPushedFcn = createCallbackFcn(app, @ReturnHomeButtonPushed);
            app.ReturnHome.Text = 'Return Home';
            app.Refresh = uibutton(app.nanotec, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed);
            app.Refresh.Text = 'Refresh Status';
            app.msp432 = uitab(app.TabGroup);
            app.msp432.Units = 'pixels';
            app.msp432.Title = 'MSP432';
            app.Preview = uitab(app.TabGroup);
            app.Preview.Units = 'pixels';
            app.Preview.Title = 'Preview';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PlantPlatform()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end