classdef DataSortingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AcceptedButton                  matlab.ui.control.Button
        Need_AttentionButton            matlab.ui.control.Button
        DirectoryEditFieldLabel         matlab.ui.control.Label
        DirectoryEditField              matlab.ui.control.EditField
        BrowseButton                    matlab.ui.control.Button
        ofAcceptedEditFieldLabel        matlab.ui.control.Label
        ofAcceptedEditField             matlab.ui.control.NumericEditField
        ofNeed_AttentionEditFieldLabel  matlab.ui.control.Label
        ofNeed_AttentionEditField       matlab.ui.control.NumericEditField
        ofSortedSamplesEditFieldLabel   matlab.ui.control.Label
        ofSortedSamplesEditField        matlab.ui.control.NumericEditField
        ofImagesLabel                   matlab.ui.control.Label
        ofImagesEditField               matlab.ui.control.NumericEditField
        ofUnsortedSamplesEditFieldLabel  matlab.ui.control.Label
        ofUnsortedSamplesEditField      matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        N;
        i;
        % Displaying the first Image
        Root_Folder;
        current_image;
        images;
        
        % Variable Initialisation
        file_list
        nImages;
        nSortedImages = 0;
        nUnsortedImages = 0;
        nAccepted = 0;
        nNeed_Attention = 0;
    end
    
    methods (Access = public)
        
        function results = fieldUpdate(app)
            % values of the # fields
            app.ofImagesEditField.Value = app.nImages;
            app.ofSortedSamplesEditField.Value = app.nSortedImages;
            app.ofUnsortedSamplesEditField.Value = app.nUnsortedImages;
            
            app.ofAcceptedEditField.Value = app.nAccepted;
            app.ofNeed_AttentionEditField.Value = app.nNeed_Attention;
            
            if app.nUnsortedImages ~= 0
                % Turning on all the classifiecation buttons
                app.AcceptedButton.Enable = 'on';
                app.Need_AttentionButton.Enable = 'on';
                
            else
%                 delete(app.file_list)
                uialert('Delete mesages now');
            end
            
        end
        
        function results = disableButtons(app)
            
            % Turning off all the classifiecation buttons
            app.AcceptedButton.Enable = 'off';
            app.Need_AttentionButton.Enable = 'off';
        end
        
        function results = moveToAccepted(app)
            
            % Copying Image in to the Accepted folder
            
            imwrite(app.current_image,fullfile(app.Root_Folder,'Accepted',sprintf('image%d.tiff',app.i)));
            
            % Updating the number of Mildew_Infected samples
            
            app.nAccepted = app.nAccepted + 1;
            
            % Moving to the next Image
            app.i = app.i + 1;
            app.nSortedImages = app.nSortedImages + 1;
            app.nUnsortedImages = app.nUnsortedImages - 1;
            
            if app.nUnsortedImages ~= 0
                
                app.current_image = read(app.images,app.i); % Reading the data on specify location
                image(app.UIAxes,app.current_image,'CDataMapping','scaled');
                drawnow;
                
            end
        end
        
        function results = moveToNeed_Attention(app)
            
            % Copying Image in to the Mildew Infected folder
            
            imwrite(app.current_image,fullfile(app.Root_Folder,'Need_Attention',sprintf('image%d.tiff',app.i)));
            
            % Updating the number of NotaSample samples
            
            app.nNeed_Attention = app.nNeed_Attention + 1;
            
            % Moving to the next Image
            
            app.i = app.i + 1;
            app.nSortedImages = app.nSortedImages + 1;
            app.nUnsortedImages = app.nUnsortedImages - 1;
            
            if app.nUnsortedImages ~= 0
                
                app.current_image = read(app.images,app.i); % Reading the data on specify location
                image(app.UIAxes,app.current_image,'CDataMapping','scaled');
                drawnow;
            end
        end
        
        function results = BrowsePress(app)
            %Get directory
            
            app.Root_Folder = uigetdir;
            h = uialert('Loading Images. Please wait.');
            % Directory field update
            
            app.DirectoryEditField.Value = app.Root_Folder;
            
            % Importing images location
            app.file_list = dir(fullfile(app.Root_Folder,'**\*.tif'));
            imgpaths = arrayfun(@(x)fullfile(x.folder, x.name),app.file_list,'UniformOutput',false);
            app.images = imageSet(imgpaths);
            
            app.i = 0;
            app.N = app.images.Count; % Counting the number of images
            
            % Varialble Initialization
            
            app.nImages = app.N;
            app.nSortedImages = 0;
            app.nUnsortedImages = app.N;
            app.nAccepted = 0;
            app.nNeed_Attention = 0;


            % Creating classification folders
            
            mkdir(fullfile(app.Root_Folder),'Accepted');
            mkdir(fullfile(app.Root_Folder),'Need_Attention');
            app.Accepted = table();
            % Copying all the files to the unsorted folder
            
            h = waitbar(0,'Please wait...');
            for j = 1:app.N
                
                app.current_image = read(app.images,j);
                imwrite(app.current_image,fullfile(app.Root_Folder,'Unsorted',app.file_list(app.i).name));
                j = j + 1;
                
                waitbar(j / app.N)
            end
            close(h)


            % Displaying the First Image
            
            app.i = 1;
            app.current_image = read(app.images,app.i); % Reading the data on specify location
            image(app.UIAxes,app.current_image,'CDataMapping','scaled');
            drawnow;
            delete(h);
        end
        
        function moveToHealthy(app)
            
            % Copying Image in to the Healthy folder
            
            imwrite(app.current_image,fullfile(app.Root_Folder,'Accepted',app.file_list(app.i).name));
            
            % Updating the number of healthy samples
            
            app.nAccepted = app.nAccepted + 1;
            
            % Moving to the next Image
            app.i = app.i + 1;
            app.nSortedImages = app.nSortedImages + 1;
            app.nUnsortedImages = app.nUnsortedImages - 1;
            
            if app.nUnsortedImages ~= 0
                
                app.current_image = read(app.images,app.i); % Reading the data on specify location
                image(app.UIAxes,app.current_image,'CDataMapping','scaled');
                drawnow;
            end
        end
        
    end
    

    methods (Access = private)

        % Callback function: BrowseButton, ofImagesEditField
        function BrowseButtonPushed(app, event)
            
            disableButtons(app)
            
            BrowsePress(app);
            
            fieldUpdate(app);
            
            
        end

        % Button pushed function: AcceptedButton
        function AcceptedButtonPushed(app, event)
            
            disableButtons(app)
            
            moveToHealthy(app)
            
            fieldUpdate(app);
            
        end

        % Button pushed function: Need_AttentionButton
        function Need_AttentionButtonPushed(app, event)
            
            disableButtons(app);
            
            moveToNeed_Attention(app);
            
            fieldUpdate(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AcceptedButton = uibutton(app.UIFigure, 'push');
            app.AcceptedButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptedButtonPushed, true);
            app.AcceptedButton.Enable = 'off';
            app.AcceptedButton.Text = 'Accepted';
            app.Need_AttentionButton = uibutton(app.UIFigure, 'push');
            app.Need_AttentionButton.ButtonPushedFcn = createCallbackFcn(app, @Need_AttentionButtonPushed, true);
            app.Need_AttentionButton.Enable = 'off';
            app.Need_AttentionButton.Text = 'Need_Attention';
            app.DirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ofAcceptedEditFieldLabel = uilabel(app.UIFigure);
            app.ofAcceptedEditFieldLabel.Text = '# of Accepted';
            app.ofAcceptedEditField = uieditfield(app.UIFigure, 'numeric');
            app.ofAcceptedEditField.ValueDisplayFormat = '%d';
            app.ofAcceptedEditField.Editable = 'off';
            app.ofNeed_AttentionEditFieldLabel = uilabel(app.UIFigure);
            app.ofNeed_AttentionEditFieldLabel.Text = '# of Need_Attention';
            app.ofNeed_AttentionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ofNeed_AttentionEditField.ValueDisplayFormat = '%d';
            app.ofNeed_AttentionEditField.Editable = 'off';
            app.ofSortedSamplesEditFieldLabel = uilabel(app.UIFigure);
            app.ofSortedSamplesEditFieldLabel.Text = '# of Sorted Samples';
            app.ofSortedSamplesEditField = uieditfield(app.UIFigure, 'numeric');
            app.ofSortedSamplesEditField.ValueDisplayFormat = '%d';
            app.ofSortedSamplesEditField.Editable = 'off';
            app.ofImagesLabel = uilabel(app.UIFigure);
            app.ofImagesLabel.Text = '# of Images';
            app.ofImagesEditField = uieditfield(app.UIFigure, 'numeric');
            app.ofImagesEditField.ValueDisplayFormat = '%d';
            app.ofImagesEditField.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.ofImagesEditField.Editable = 'off';
            app.ofUnsortedSamplesEditFieldLabel = uilabel(app.UIFigure);
            app.ofUnsortedSamplesEditFieldLabel.Text = '# of Unsorted Samples ';
            app.ofUnsortedSamplesEditField = uieditfield(app.UIFigure, 'numeric');
            app.ofUnsortedSamplesEditField.ValueDisplayFormat = '%d';
            app.ofUnsortedSamplesEditField.Editable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image to Classify')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 2448];
            app.UIAxes.YLim = [0 2048];
        end
    end

    methods (Access = public)

        % Construct app
        function app = DataSortingApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end